classdef APE_GUI_SM_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APEGUISM14UIFigure          matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        CommandsPanel               matlab.ui.container.Panel
        AnswerTextArea              matlab.ui.control.TextArea
        AnswerTextAreaLabel         matlab.ui.control.Label
        CommandListBox              matlab.ui.control.ListBox
        CommandListBoxLabel         matlab.ui.control.Label
        TCPIPcommunicationPanel     matlab.ui.container.Panel
        StopcommunicationButton     matlab.ui.control.Button
        StartcommunicationButton    matlab.ui.control.Button
        PortEditField               matlab.ui.control.NumericEditField
        PortEditFieldLabel          matlab.ui.control.Label
        APEIPaddressEditField       matlab.ui.control.EditField
        APEIPaddressEditFieldLabel  matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        SpectruminfoPanel           matlab.ui.container.Panel
        PowermWTextArea             matlab.ui.control.TextArea
        PowermWTextAreaLabel        matlab.ui.control.Label
        FWHMnmTextArea              matlab.ui.control.TextArea
        FWHMnmTextAreaLabel         matlab.ui.control.Label
        WavelengthnmTextArea        matlab.ui.control.TextArea
        WavelengthnmTextAreaLabel   matlab.ui.control.Label
        XmaxEditField               matlab.ui.control.NumericEditField
        XmaxEditFieldLabel          matlab.ui.control.Label
        XminEditField               matlab.ui.control.NumericEditField
        XminEditFieldLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        StatusPanel                 matlab.ui.container.Panel
        StatusLamp                  matlab.ui.control.Lamp
        StatusLampLabel             matlab.ui.control.Label
        HumidityLabel               matlab.ui.control.Label
        HumidityGauge               matlab.ui.control.SemicircularGauge
        TempLabel                   matlab.ui.control.Label
        TemperatureGauge            matlab.ui.control.SemicircularGauge
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        address string = '10.48.14.22'; % IP of the client
        command string = 'state?'; % String sent to the client
        in
        out
        port double {mustBePositive(port), mustBeInteger(port), mustBeFinite(port)} = 51123; % Port of the client
        tcp_socket

        xmin double = 700 % Wevelength min
        xmax double = 900 % Wavelength max

    end

    methods (Access = private)
        %% Check the current status of the laser
        function get_status(app)
            app.out.writeBytes(sprintf('temperature?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.TemperatureGauge.Value=str2double(strrep(str,',','.'));
            app.out.writeBytes(sprintf('humidity?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.HumidityGauge.Value=str2double(strrep(str,',','.'));
            app.out.writeBytes(sprintf('state?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            if contains(str,'error')
                app.StatusLamp.Color=[1,0,0];
            else
                app.StatusLamp.Color=[0,1,0];
            end%if
        end
        

        %% Get the position of cavity actuator
        function cavity_pos=get_cavity_pos(app)
            app.out.writeBytes(sprintf('cavity_position?\r\n'));
            app.out.flush();
            cavity_pos=char(app.in.readLine());
        end

        %% Get cavity position (agilis)
        function cavity=get_cavity(app)
            app.out.writeBytes(sprintf('set_cavity?\r\n'));
            app.out.flush();
            cavity=char(app.in.readLine());
        end

        %% Get busy state of cavity actuator
        function cavity_busy=get_cavity_busy(app)
            app.out.writeBytes(sprintf('cavity_busy?\r\n'));
            app.out.flush();
            cavity_busy=char(app.in.readLine());
        end


        %% Get the OPO state
        function idle = check_status_idle(app)
            app.out.writeBytes(sprintf('state?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            if contains(str,'idle')
                idle='TRUE';
            else
                idle='FALSE';
            end%if
        end


        %% Get the expected signal wavelength
        function str=get_signal_wavelength(app)
            app.out.writeBytes(sprintf('set_signal_wavelength?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
        end

        %% Get state of the pump shutter
        function pump_open = check_pump_open(app)
            app.out.writeBytes(sprintf('pump_open?\r\n'));
            app.out.flush();
            pump_open=char(app.in.readLine);
        end

 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        function spectrum=Spectrum_display(app)
            app.out.writeBytes(sprintf('spectrum?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            str=str(8:end);% remove some useless charapp.out.writeBytes(sprintf(strcat(app.command,'\r\n')));
            spectrum = cell2mat(textscan(str,'%f'));%convert a cell char array to an array
            plot(app.UIAxes,spectrum(1:2:end),spectrum(2:2:end));% plot the spectrum

            Check_spectrum_values(app);
        end

        function Check_spectrum_values(app)
            signal_wavelength(app);
            bandwidth(app);
            signal_power(app);
        end

        function str=signal_wavelength(app)
            app.out.writeBytes(sprintf('signal_wavelength?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.WavelengthnmTextArea.Value=str;
        end
        function str=bandwidth(app)
            app.out.writeBytes(sprintf('bandwidth?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.FWHMnmTextArea.Value=str;
        end
        function str=signal_power(app)
            app.out.writeBytes(sprintf('signal_power?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.PowermWTextArea.Value=str;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.APEGUISM14UIFigure.Visible = 'off';
            movegui(app.APEGUISM14UIFigure,"center")
            app.APEGUISM14UIFigure.Visible = 'on';
        end

        % Button pushed function: StartcommunicationButton
        function StartcommunicationButtonPushed(app, event)
            % import java tcp socket libraries
            import java.io.*;
            import java.net.*;
            app.AnswerTextArea.Value='Starting procedure... please wait!';
            %open socket connection to the remote target
            app.tcp_socket = Socket(app.address,app.port);
            %create socket-writer and -reader
            app.in =  DataInputStream(app.tcp_socket.getInputStream);
            app.out = DataOutputStream(app.tcp_socket.getOutputStream) ;
            pause(5);
            get_status(app);
            Spectrum_display(app);
            Check_spectrum_values(app);
            app.AnswerTextArea.Value='Start communication -> OK!';
        end

        % Button pushed function: StopcommunicationButton
        function StopcommunicationButtonPushed(app, event)
            app.out.close();
            app.in.close();
            app.tcp_socket.close();
            app.AnswerTextArea.Value = 'Application stopped!';
        end

        % Value changed function: APEIPaddressEditField
        function APEIPaddressEditFieldValueChanged(app, event)
            app.address = app.APEIPaddressEditField.Value;
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            app.port = app.PortEditField.Value;
        end

        % Value changed function: XmaxEditField
        function XmaxEditFieldValueChanged(app, event)
            app.xmax= app.XmaxEditField.Value;
            app.UIAxes.XLim=[app.xmin, app.xmax];
        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
            app.xmin = app.XminEditField.Value;
            app.UIAxes.XLim=[app.xmin, app.xmax];
        end

        % Value changed function: CommandListBox
        function CommandListBoxValueChanged(app, event)
            app.command = app.CommandListBox.Value;
            app.AnswerTextArea.Value = strcat(app.command,'\r\n');
            app.out.writeBytes(sprintf(strcat(app.command,'\r\n')));
            app.out.flush();
            str=char(app.in.readLine());
            switch app.command
                case 'signal_wavelength?'
                    app.AnswerTextArea.Value=str;
                case 'pump_power?'
                    app.AnswerTextArea.Value=str;
                case 'signal_power?'
                    app.PowermWTextArea.Value=str;
                case 'set_signal_wavelength?'
                    app.AnswerTextArea.Value=str;
                case 'spectrum?'
                    str=str(8:end);% remove some useless charapp.out.writeBytes(sprintf(strcat(app.command,'\r\n')));
                    spectrum = cell2mat(textscan(str,'%f'));%convert a cell char array to an array
                    plot(app.UIAxes,spectrum(1:2:end),spectrum(2:2:end));% plot the spectrum
                    Check_spectrum_values(app);
                otherwise
                    app.AnswerTextArea.Value=str;
            end%switch

        end

        % Close request function: APEGUISM14UIFigure
        function APEGUISM14UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.APEGUISM14UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {757, 757, 757};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {757, 757};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x', 91};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.APEGUISM14UIFigure = uifigure('Visible', 'off');
            app.APEGUISM14UIFigure.Name = 'APE GUI SM 1.4';
            app.APEGUISM14UIFigure.CloseRequestFcn = createCallbackFcn(app, @APEGUISM14UIFigureCloseRequest, true);
            app.APEGUISM14UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.APEGUISM14UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TCPIPcommunicationPanel = uipanel(app.LeftPanel);
            app.TCPIPcommunicationPanel.Title = 'TCP/IP communication';
            app.APEIPaddressEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.APEIPaddressEditFieldLabel.Text = 'APE IP address';
            app.APEIPaddressEditField = uieditfield(app.TCPIPcommunicationPanel, 'text');
            app.APEIPaddressEditField.ValueChangedFcn = createCallbackFcn(app, @APEIPaddressEditFieldValueChanged, true);
            app.APEIPaddressEditField.Value = '10.48.14.22';
            app.PortEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.TCPIPcommunicationPanel, 'numeric');
            app.PortEditField.Limits = [0 Inf];
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 51123;
            app.StartcommunicationButton = uibutton(app.TCPIPcommunicationPanel, 'push');
            app.StartcommunicationButton.ButtonPushedFcn = createCallbackFcn(app, @StartcommunicationButtonPushed, true);
            app.StartcommunicationButton.Text = 'Start communication';
            app.StopcommunicationButton = uibutton(app.TCPIPcommunicationPanel, 'push');
            app.StopcommunicationButton.ButtonPushedFcn = createCallbackFcn(app, @StopcommunicationButtonPushed, true);
            app.StopcommunicationButton.Text = 'Stop communication';
            app.CommandsPanel = uipanel(app.LeftPanel);
            app.CommandsPanel.Title = 'Commands';
            app.CommandListBoxLabel = uilabel(app.CommandsPanel);
            app.CommandListBoxLabel.Text = 'Command';
            app.CommandListBox = uilistbox(app.CommandsPanel);
            app.CommandListBox.Items = {'-----------------------------------------------', 'spectrum?', '-----------------------------------------------', 'set_cavity?', 'set_cavity_fullsteps?', 'set_cavity_substeps?', 'cavity_position?', 'cavity_busy?', '-----------------------------------------------', 'set_lyot?', 'set_lyot_steplength1?', 'set_lyot_steplength2?', 'lyot_position?', 'lyot_busy?', '-----------------------------------------------', 'set_xtal_temperature?', 'xtal_temperature?', 'xtal_temp_stabilized?', 'thermo_status?', '-----------------------------------------------', 'set_signal_wavelength?', 'automatic?', 'pump_open?', 'state?'};
            app.CommandListBox.ValueChangedFcn = createCallbackFcn(app, @CommandListBoxValueChanged, true);
            app.CommandListBox.Value = 'spectrum?';
            app.AnswerTextAreaLabel = uilabel(app.CommandsPanel);
            app.AnswerTextAreaLabel.Text = 'Answer';
            app.AnswerTextArea = uitextarea(app.CommandsPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.StatusPanel = uipanel(app.CenterPanel);
            app.StatusPanel.Title = 'Status';
            app.TemperatureGauge = uigauge(app.StatusPanel, 'semicircular');
            app.TemperatureGauge.Limits = [0 50];
            app.TempLabel = uilabel(app.StatusPanel);
            app.TempLabel.Text = 'Temp';
            app.HumidityGauge = uigauge(app.StatusPanel, 'semicircular');
            app.HumidityGauge.Limits = [0 50];
            app.HumidityGauge.ScaleDirection = 'counterclockwise';
            app.HumidityLabel = uilabel(app.StatusPanel);
            app.HumidityLabel.Text = 'Humidity';
            app.StatusLampLabel = uilabel(app.StatusPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.SpectruminfoPanel = uipanel(app.CenterPanel);
            app.SpectruminfoPanel.Title = 'Spectrum info';
            app.UIAxes = uiaxes(app.SpectruminfoPanel);
            title(app.UIAxes, 'Spectrum')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (a.u)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [750 900];
            app.XminEditFieldLabel = uilabel(app.SpectruminfoPanel);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.SpectruminfoPanel, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.XminEditField.Value = 750;
            app.XmaxEditFieldLabel = uilabel(app.SpectruminfoPanel);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.SpectruminfoPanel, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxEditFieldValueChanged, true);
            app.XmaxEditField.Value = 900;
            app.WavelengthnmTextAreaLabel = uilabel(app.SpectruminfoPanel);
            app.WavelengthnmTextAreaLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmTextArea = uitextarea(app.SpectruminfoPanel);
            app.FWHMnmTextAreaLabel = uilabel(app.SpectruminfoPanel);
            app.FWHMnmTextAreaLabel.Text = 'FWHM (nm)';
            app.FWHMnmTextArea = uitextarea(app.SpectruminfoPanel);
            app.PowermWTextAreaLabel = uilabel(app.SpectruminfoPanel);
            app.PowermWTextAreaLabel.Text = 'Power (mW)';
            app.PowermWTextArea = uitextarea(app.SpectruminfoPanel);
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-if-marseille_fond_transparent.png');
            app.Image2 = uiimage(app.CenterPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'APE.png');
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.APEGUISM14UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APE_GUI_SM_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APEGUISM14UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APEGUISM14UIFigure)
        end
    end
end