classdef APE_GUI_SM_v1_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APEGUISM14UIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CommandsPanel                 matlab.ui.container.Panel
        AnswerTextArea                matlab.ui.control.TextArea
        AnswerTextAreaLabel           matlab.ui.control.Label
        CommandListBox                matlab.ui.control.ListBox
        CommandListBoxLabel           matlab.ui.control.Label
        TCPIPcommunicationPanel       matlab.ui.container.Panel
        StopcommunicationButton       matlab.ui.control.Button
        StartcommunicationButton      matlab.ui.control.Button
        PortEditField                 matlab.ui.control.NumericEditField
        PortEditFieldLabel            matlab.ui.control.Label
        APEIPaddressEditField         matlab.ui.control.EditField
        APEIPaddressEditFieldLabel    matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        WevlengthChangePanel          matlab.ui.container.Panel
        WavelengthnmEditField_2       matlab.ui.control.NumericEditField
        WavelengthnmEditField_2Label  matlab.ui.control.Label
        SpectruminfoPanel             matlab.ui.container.Panel
        PowermWTextArea               matlab.ui.control.TextArea
        PowermWTextAreaLabel          matlab.ui.control.Label
        FWHMnmTextArea                matlab.ui.control.TextArea
        FWHMnmTextAreaLabel           matlab.ui.control.Label
        WavelengthnmTextArea          matlab.ui.control.TextArea
        WavelengthnmTextAreaLabel     matlab.ui.control.Label
        XmaxEditField                 matlab.ui.control.NumericEditField
        XmaxEditFieldLabel            matlab.ui.control.Label
        XminEditField                 matlab.ui.control.NumericEditField
        XminEditFieldLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        StatusPanel                   matlab.ui.container.Panel
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        HumidityLabel                 matlab.ui.control.Label
        HumidityGauge                 matlab.ui.control.SemicircularGauge
        TempLabel                     matlab.ui.control.Label
        TemperatureGauge              matlab.ui.control.SemicircularGauge
        RightPanel                    matlab.ui.container.Panel
        TuneRecordPanel               matlab.ui.container.Panel
        Gauge                         matlab.ui.control.LinearGauge
        GaugeLabel                    matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        stepsEditField                matlab.ui.control.NumericEditField
        stepsEditFieldLabel           matlab.ui.control.Label
        MaxEditField                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MinEditFieldLabel             matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        TuningPanel                   matlab.ui.container.Panel
        Step15Lamp                    matlab.ui.control.Lamp
        Step15LampLabel               matlab.ui.control.Label
        Step14Lamp                    matlab.ui.control.Lamp
        Step14Lamp_2Label             matlab.ui.control.Label
        Step13Lamp                    matlab.ui.control.Lamp
        Step13Lamp_2Label             matlab.ui.control.Label
        Step12Lamp                    matlab.ui.control.Lamp
        Step12Lamp_2Label             matlab.ui.control.Label
        Step11Lamp                    matlab.ui.control.Lamp
        Step11Lamp_2Label             matlab.ui.control.Label
        Step10Lamp                    matlab.ui.control.Lamp
        Step10Lamp_2Label             matlab.ui.control.Label
        Step9Lamp                     matlab.ui.control.Lamp
        Step9Lamp_2Label              matlab.ui.control.Label
        Step8Lamp                     matlab.ui.control.Lamp
        Step8Lamp_2Label              matlab.ui.control.Label
        Step7Lamp                     matlab.ui.control.Lamp
        Step7Lamp_2Label              matlab.ui.control.Label
        Step6Lamp                     matlab.ui.control.Lamp
        Step6Lamp_2Label              matlab.ui.control.Label
        Step5Lamp                     matlab.ui.control.Lamp
        Step5Lamp_2Label              matlab.ui.control.Label
        Step4Lamp                     matlab.ui.control.Lamp
        Step4LampLabel                matlab.ui.control.Label
        Step3Lamp                     matlab.ui.control.Lamp
        Step3LampLabel                matlab.ui.control.Label
        Step2Lamp                     matlab.ui.control.Lamp
        Step2LampLabel                matlab.ui.control.Label
        Step1Lamp                     matlab.ui.control.Lamp
        Step1LampLabel                matlab.ui.control.Label
        XtalstabLamp                  matlab.ui.control.Lamp
        XtalstabLampLabel             matlab.ui.control.Label
        IdleLamp                      matlab.ui.control.Lamp
        IdleLampLabel                 matlab.ui.control.Label
        PumpLamp                      matlab.ui.control.Lamp
        PumpLampLabel                 matlab.ui.control.Label
        ManualLamp                    matlab.ui.control.Lamp
        ManualLampLabel               matlab.ui.control.Label
        TuneButton                    matlab.ui.control.Button
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel    matlab.ui.control.Label
        AdjustmentsPanel              matlab.ui.container.Panel
        XtaltemperaturePanel          matlab.ui.container.Panel
        XtaltemperatureSetEditField   matlab.ui.control.NumericEditField
        SetEditField_3Label           matlab.ui.control.Label
        XtaltemperatureText           matlab.ui.control.TextArea
        Label_3                       matlab.ui.control.Label
        XtaltemperatureIncPlus        matlab.ui.control.Button
        XtaltemperatureInc            matlab.ui.control.Button
        XtaltemperatureDec            matlab.ui.control.Button
        XtaltemperaturePlus           matlab.ui.control.Button
        LyotanglePanel                matlab.ui.container.Panel
        LyotangleSetEditField         matlab.ui.control.NumericEditField
        SetEditField_2Label           matlab.ui.control.Label
        LyotangleText                 matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        LyotangleIncPlus              matlab.ui.control.Button
        LyotangleInc                  matlab.ui.control.Button
        LyotangleDec                  matlab.ui.control.Button
        LyotangleDecPlus              matlab.ui.control.Button
        CavitylengthPanel             matlab.ui.container.Panel
        CavitylengthSetEditField      matlab.ui.control.NumericEditField
        SetEditFieldLabel             matlab.ui.control.Label
        CavitylengthText              matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        CavitylengthIncPlus           matlab.ui.control.Button
        CavitylengthInc               matlab.ui.control.Button
        CavitylengthDec               matlab.ui.control.Button
        CavitylengthDecPlus           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        address string = '10.48.14.22'; % IP of the client
        command string = 'state?'; % String sent to the client
        in
        out
        port double {mustBePositive(port), mustBeInteger(port), mustBeFinite(port)} = 51123; % Port of the client
        tcp_socket

        xmin double = 700 % Wevelength min
        xmax double = 900 % Wavelength max

    end

    methods (Access = private)
        %% Check the current status of the laser
        function get_status(app)
            app.out.writeBytes(sprintf('temperature?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.TemperatureGauge.Value=str2double(strrep(str,',','.'));
            app.out.writeBytes(sprintf('humidity?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.HumidityGauge.Value=str2double(strrep(str,',','.'));
            app.out.writeBytes(sprintf('state?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            if contains(str,'error')
                app.StatusLamp.Color=[1,0,0];
            else
                app.StatusLamp.Color=[0,1,0];
            end%if

            app.out.writeBytes(sprintf('set_cavity?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.CavitylengthText.Value=str;

            app.out.writeBytes(sprintf('set_lyot?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.LyotangleText.Value=str;

            app.out.writeBytes(sprintf('set_xtal_temperature?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.XtaltemperatureText.Value=str;

        end

        %% Correct the offset of the position calibration of the cavity actuator with respect to the measured signal wavelength
        function correct_cavity_offset(app)
            app.out.writeBytes(sprintf('correct_cavity_offset\r\n'));
            app.out.flush();
        end

        %% Start/stop automatic maximization of the output power (uses Lyot actuator)
        function max_power_lyot(app)
            app.out.writeBytes(sprintf('max_power_lyot=TRUE\r\n'));
            app.out.flush();
        end

        %% Get busy state of cavity actuator
        function lyot_busy=get_lyot_busy(app)
            app.out.writeBytes(sprintf('lyot_busy?\r\n'));
            app.out.flush();
            lyot_busy=char(app.in.readLine());
        end

        %% Get state of the measured crystal temperature
        function xtal_temp_stab=get_xtaltemperature_stab(app)
            app.out.writeBytes(sprintf('xtal_temp_stabilized?\r\n'));
            app.out.flush();
            xtal_temp_stab=char(app.in.readLine());
        end

        %% Set phase‐matching temperature to the calibrated value for the set signal wavelength
        function set_xtaltemperature_lambda(app)
            app.out.writeBytes(sprintf('set_temp_lambda\r\n'));
            app.out.flush();
        end

        %% Get the crystal temperature in degree C
        function xtal_temp=get_xtaltemperature_lambda(app)
            app.out.writeBytes(sprintf('set_xtal_temperature?\r\n'));
            app.out.flush();
            xtal_temp=char(app.in.readLine());
        end

        %% Get the measured crystal temperature in degree C
        function xtal_temp_measured=get_measuredxtaltemperature_lambda(app)
            app.out.writeBytes(sprintf('xtal_temperature?\r\n'));
            app.out.flush();
            xtal_temp_measured=char(app.in.readLine());
        end


        %% Set cavity actuator to the calibrated position for the set signal wavelength
        function set_cavity_lambda(app)
            app.out.writeBytes(sprintf('set_cavity_lambda\r\n'));
            app.out.flush();
        end

        %% Get the position of cavity actuator
        function cavity_pos=get_cavity_pos(app)
            app.out.writeBytes(sprintf('cavity_position?\r\n'));
            app.out.flush();
            cavity_pos=char(app.in.readLine());
        end

        %% Get cavity position (agilis)
        function cavity=get_cavity(app)
            app.out.writeBytes(sprintf('set_cavity?\r\n'));
            app.out.flush();
            cavity=char(app.in.readLine());
        end

        %% Get busy state of cavity actuator
        function cavity_busy=get_cavity_busy(app)
            app.out.writeBytes(sprintf('cavity_busy?\r\n'));
            app.out.flush();
            cavity_busy=char(app.in.readLine());
        end


        %% Get the OPO state
        function idle = check_status_idle(app)
            app.out.writeBytes(sprintf('state?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            if contains(str,'idle')
                idle='TRUE';
            else
                idle='FALSE';
            end%if
        end

        %% Set the new signal wavelength
        function set_signal_wavelength(app,value)
            app.out.writeBytes(sprintf(strcat('set_signal_wavelength=',string(value),'\r\n')));
            app.out.flush();
            app.AnswerTextArea.Value=string(value);
        end

        %% Get the expected signal wavelength
        function str=get_signal_wavelength(app)
            app.out.writeBytes(sprintf('set_signal_wavelength?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
        end

        %% Get state of the pump shutter
        function pump_open = check_pump_open(app)
            app.out.writeBytes(sprintf('pump_open?\r\n'));
            app.out.flush();
            pump_open=char(app.in.readLine);
        end

        %% Set state of the output shutter (for signal and idler)
        function set_pumpopen(app)
            app.out.writeBytes(sprintf('pump_open=TRUE\r\n'));
            app.out.flush();
        end

        %% Get the state of the automatic tuning function
        function manual = check_manual_mode(app)
            app.out.writeBytes(sprintf('automatic?\r\n'));
            app.out.flush();
            str=char(app.in.readLine);
            if strcmp(str,'FALSE')
                manual='TRUE';
            else
                manual='FALSE';
            end%if
        end

        %% Set new state of the automatic tuning function
        function set_manual(app)
            app.out.writeBytes(sprintf('automatic=FALSE\r\n'));
            app.out.flush();
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Increment cavity position by current number of sub steps
        function str=set_cavity(app)
            app.out.writeBytes(sprintf('set_cavity?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.CavitylengthText.Value=str;
        end


        function sub_inc_cavity(app)
            cavity_pos=str2double(get_cavity_pos(app));
            app.out.writeBytes(sprintf(strcat('set_cavity=',string(cavity_pos+5),'\r\n')));
            app.LyotangleSetEditField=cavity_pos+5;
            %app.out.writeBytes(sprintf('sub_inc_cavity\r\n'));
            app.out.flush();
        end

        %% Decrement cavity position by current number of sub steps
        function sub_dec_cavity(app)
            %app.out.writeBytes(sprintf('sub_dec_cavity\r\n'));
            cavity_pos=str2double(get_cavity_pos(app));
            app.out.writeBytes(sprintf(strcat('set_cavity=',string(cavity_pos-5),'\r\n')));
            app.LyotangleSetEditField=cavity_pos-5;
            app.out.flush();
        end

        %% Increment cavity position by current number of full steps
        function full_inc_cavity(app)
            % app.out.writeBytes(sprintf('full_inc_cavity\r\n'));
            cavity_pos=str2double(get_cavity_pos(app));
            app.out.writeBytes(sprintf(strcat('set_cavity=',string(cavity_pos+10),'\r\n')));
            app.LyotangleSetEditField=cavity_pos-10;
            app.out.flush();
        end

        %% Decrement cavity position by current number of full step
        function full_dec_cavity(app)
            % app.out.writeBytes(sprintf('full_dec_cavity\r\n'));
            cavity_pos=str2double(get_cavity_pos(app));
            app.out.writeBytes(sprintf(strcat('set_cavity=',string(cavity_pos-10),'\r\n')));
            app.LyotangleSetEditField=cavity_pos-10;
            app.out.flush();
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function str=set_lyot(app)
            app.out.writeBytes(sprintf('set_lyot?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.LyotangleText.Value=str;
        end


        %% Increment lyot position by current number of sub steps
        function step_lyot_inc1(app)
            % app.out.writeBytes(sprintf('step_lyot_inc1\r\n'));
            Lyot_pos=str2double(set_lyot(app));
            app.out.writeBytes(sprintf(strcat('set_lyot=',string(Lyot_pos+5),'\r\n')));
            app.out.flush();
        end

        %% Decrement lyot position by current number of sub steps
        function step_lyot_dec1(app)
            % app.out.writeBytes(sprintf('step_lyot_dec1\r\n'));
            Lyot_pos=str2double(set_lyot(app));
            app.out.writeBytes(sprintf(strcat('set_lyot=',string(Lyot_pos-5),'\r\n')));
            app.out.flush();
        end

        %% Increment lyot position by current number of full steps
        function step_lyot_inc2(app)
            % app.out.writeBytes(sprintf('step_lyot_inc2\r\n'));
            Lyot_pos=str2double(set_lyot(app));
            app.out.writeBytes(sprintf(strcat('set_lyot=',string(Lyot_pos+10),'\r\n')));
            app.out.flush();
        end

        %% Decrement lyot position by current number of full step
        function step_lyot_dec2(app)
            %app.out.writeBytes(sprintf('step_lyot_dec2\r\n'));
            Lyot_pos=str2double(set_lyot(app));
            app.out.writeBytes(sprintf(strcat('set_lyot=',string(Lyot_pos-10),'\r\n')));
            app.out.flush();
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        function spectrum=Spectrum_display(app)
            app.out.writeBytes(sprintf('spectrum?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            str=str(8:end);% remove some useless charapp.out.writeBytes(sprintf(strcat(app.command,'\r\n')));
            spectrum = cell2mat(textscan(str,'%f'));%convert a cell char array to an array
            plot(app.UIAxes,spectrum(1:2:end),spectrum(2:2:end));% plot the spectrum

            Check_spectrum_values(app);
        end

        function Check_spectrum_values(app)
            signal_wavelength(app);
            bandwidth(app);
            signal_power(app);
        end

        function str=signal_wavelength(app)
            app.out.writeBytes(sprintf('signal_wavelength?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.WavelengthnmTextArea.Value=str;
        end
        function str=bandwidth(app)
            app.out.writeBytes(sprintf('bandwidth?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.FWHMnmTextArea.Value=str;
        end
        function str=signal_power(app)
            app.out.writeBytes(sprintf('signal_power?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.PowermWTextArea.Value=str;
        end

        function Save_plot(app,data,output_path,spectrum)
            figure(1);
            plot(data(1,:),data(2,:));
            title('FWHM vs. Wavelength');
            xlabel('Wavelength (nm)');
            ylabel('FWHM (nm)');
            strcat(output_path,filesep,'FWHM.png')
            saveas(gcf,strcat(output_path,filesep,'FWHM.png'));

            figure(2);
            plot(data(1,:),data(3,:));
            title('Power vs. Wavelength');
            xlabel('Wavelength (nm)');
            ylabel('Power (mW)');
            saveas(gcf,strcat(output_path,filesep,'Power.png'));

            figure(3);
            plot(data(1,:),data(4,:));
            title('Cavity length vs. Wavelength');
            xlabel('Wavelength (nm)');
            ylabel('Cavity length (a.u.)');
            saveas(gcf,strcat(output_path,filesep,'Cavity.png'));

            figure(4);
            plot(data(1,:),data(5,:));
            title('Lyot angle vs. Wavelength');
            xlabel('Wavelength (nm)');
            ylabel('Lyot angle (a.u.)');
            saveas(gcf,strcat(output_path,filesep,'Lyot.png'));

            figure(5);
            plot(data(1,:),data(6,:));
            title('Xtal temp vs. Wavelength');
            xlabel('Wavelength (nm)');
            ylabel('Xtal temp (°C)');
            saveas(gcf,strcat(output_path,filesep,'Xtal.png'));

            figure(6);
            spectrumx=spectrum(1:2:end,:);
            spectrumy=spectrum(2:2:end,:);
            plot(spectrumx,spectrumy);
            title('Spectra');
            xlabel('Wavelength (nm)');
            ylabel('Intensity (a.u.)');
            xlim([app.MinEditField.Value-20 app.MaxEditField.Value+20])
            saveas(gcf,strcat(output_path,filesep,'Spectra.png'));

            close all;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.APEGUISM14UIFigure.Visible = 'off';
            movegui(app.APEGUISM14UIFigure,"center")
            app.APEGUISM14UIFigure.Visible = 'on';
        end

        % Button pushed function: StartcommunicationButton
        function StartcommunicationButtonPushed(app, event)
            % import java tcp socket libraries
            import java.io.*;
            import java.net.*;
            app.AnswerTextArea.Value='Starting procedure... please wait!';
            %open socket connection to the remote target
            app.tcp_socket = Socket(app.address,app.port);
            %create socket-writer and -reader
            app.in =  DataInputStream(app.tcp_socket.getInputStream);
            app.out = DataOutputStream(app.tcp_socket.getOutputStream) ;
            pause(5);
            get_status(app);
            Spectrum_display(app);
            Check_spectrum_values(app);
            app.AnswerTextArea.Value='Start communication -> OK!';
        end

        % Button pushed function: StopcommunicationButton
        function StopcommunicationButtonPushed(app, event)
            app.out.close();
            app.in.close();
            app.tcp_socket.close();
            app.AnswerTextArea.Value = 'Application stopped!';
            delete(app);
        end

        % Value changed function: APEIPaddressEditField
        function APEIPaddressEditFieldValueChanged(app, event)
            app.address = app.APEIPaddressEditField.Value;
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            app.port = app.PortEditField.Value;
        end

        % Value changed function: XmaxEditField
        function XmaxEditFieldValueChanged(app, event)
            app.xmax= app.XmaxEditField.Value;
            app.UIAxes.XLim=[app.xmin, app.xmax];
        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
            app.xmin = app.XminEditField.Value;
            app.UIAxes.XLim=[app.xmin, app.xmax];
        end

        % Value changed function: CommandListBox
        function CommandListBoxValueChanged(app, event)
            app.command = app.CommandListBox.Value;
            app.AnswerTextArea.Value = strcat(app.command,'\r\n');
            app.out.writeBytes(sprintf(strcat(app.command,'\r\n')));
            app.out.flush();
            str=char(app.in.readLine());
            switch app.command
                case 'signal_wavelength?'
                    app.AnswerTextArea.Value=str;
                case 'pump_power?'
                    app.AnswerTextArea.Value=str;
                case 'signal_power?'
                    app.PowermWTextArea.Value=str;
                case 'set_signal_wavelength?'
                    app.AnswerTextArea.Value=str;
                case 'spectrum?'
                    str=str(8:end);% remove some useless charapp.out.writeBytes(sprintf(strcat(app.command,'\r\n')));
                    spectrum = cell2mat(textscan(str,'%f'));%convert a cell char array to an array
                    plot(app.UIAxes,spectrum(1:2:end),spectrum(2:2:end));% plot the spectrum
                    % f = fit(spectrum(1:2:end),spectrum(2:2:end),'gauss1');% fit with a gaussian curve
                    % MyCoeffs = coeffvalues(f); % get the fitting coefficients
                    % app.WavelengthnmTextArea.Value=string(MyCoeffs(2));
                    % app.FWHMnmTextArea.Value=string(2*sqrt(log(2)) * MyCoeffs(3));

                    Check_spectrum_values(app);
                otherwise
                    app.AnswerTextArea.Value=str;
            end%switch

        end

        % Value changed function: CavitylengthSetEditField
        function CavitylengthSetEditFieldValueChanged(app, event)
            value=app.CavitylengthSetEditField.Value;
            app.out.writeBytes(sprintf(strcat('set_cavity=',string(value),'\r\n')));
            app.out.flush();
            get_status(app);
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            value = app.WavelengthnmEditField.Value;

        end

        % Button pushed function: TuneButton
        function TuneButtonPushed(app, event)
            app.Step1Lamp.Color=[1,0,0];
            app.Step2Lamp.Color=[1,0,0];
            app.Step3Lamp.Color=[1,0,0];
            app.Step4Lamp.Color=[1,0,0];
            app.Step5Lamp.Color=[1,0,0];
            app.Step6Lamp.Color=[1,0,0];
            app.Step7Lamp.Color=[1,0,0];
            app.Step8Lamp.Color=[1,0,0];
            app.Step9Lamp.Color=[1,0,0];
            app.Step10Lamp.Color=[1,0,0];
            app.Step11Lamp.Color=[1,0,0];
            app.Step12Lamp.Color=[1,0,0];
           % pause(3);
            % 1 - check if we are in the manual mode
            app.ManualLamp.Color=[1,0,0];
            manual = check_manual_mode(app);
            while strcmp(manual,'FALSE')
                set_manual(app);
                manual = check_manual_mode(app);
            end%while
            app.ManualLamp.Color=[0,1,0];
            app.Step1Lamp.Color=[0,1,0];
           % pause(1);
            % 2 - check if the pump shutter is open
            app.PumpLamp.Color=[1,0,0];
            pump_open = check_pump_open(app);
            while strcmp(pump_open,'FALSE')
                set_pumpopen(app);
                pump_open = check_pump_open(app);
            end%while
            app.PumpLamp.Color=[0,1,0];
            app.Step2Lamp.Color=[0,1,0];
            pause(1);
          %  % 3 - set the wavelength
            set_signal_wavelength(app,app.WavelengthnmEditField.Value);
            app.Step3Lamp.Color=[0,1,0];
          %  pause(1);
            % 4 - check if the state is idle
            app.IdleLamp.Color=[1,0,0];
            idle = check_status_idle(app);
            while strcmp(idle,'FALSE')
                set_pumpopen(app);
                idle = check_status_idle(app);
            end%while
            app.IdleLamp.Color=[0,1,0];
            app.Step4Lamp.Color=[0,1,0];

          %  pause(1);
            % 5 - Set phase‐matching temperature to the calibrated value for the set signal wavelength
            % Get the expected value
            % Get the measured value until they match (temp is stabilized)
            app.XtalstabLamp.Color=[1,0,0];
            set_xtaltemperature_lambda(app);
            app.XtaltemperatureSetEditField.Value=str2double(get_xtaltemperature_lambda(app));
            xtal_temp_stab=get_xtaltemperature_stab(app);
            % while strcmp(xtal_temp_stab,'FALSE')
            %     app.XtaltemperatureText.Value=string(get_measuredxtaltemperature_lambda(app));
            %     pause(0.1);
            %     xtal_temp_stab=get_xtaltemperature_stab(app);
            % end%
            app.XtalstabLamp.Color=[0,1,0];
            app.XtaltemperatureText.FontColor=[0,1,0];
            app.Step5Lamp.Color=[0,1,0];
        %    pause(1);
            % 6 - set_cavity_lambda(app)
            % Get the expected value
            % Get the measured value until they match (temp is stabilized)
            set_cavity_lambda(app);
            app.CavitylengthSetEditField.Value=str2double(get_cavity_pos(app));
            cavity_busy=get_cavity_busy(app);
            while strcmp(cavity_busy,'TRUE')
                app.CavitylengthText.Value=get_cavity(app);
                cavity_busy=get_cavity_busy(app);
            end
            app.CavitylengthText.FontColor=[0,1,0];
            app.Step6Lamp.Color=[0,1,0];
          %  pause(1);
            % 7 - Start automatic maximization of the output power
            max_power_lyot(app);
            lyot_busy=get_lyot_busy(app);
            while strcmp(lyot_busy,'TRUE')
                lyot_busy=get_lyot_busy(app);
                app.LyotangleText.FontColor=[1,0,0];
            end
            app.LyotangleText.FontColor=[0,1,0];
            app.Step7Lamp.Color=[0,1,0];
        %    pause(1);
            %8 - Tune Lyot angle to have the expected wavelength
            %i=0;
            % while (ismembertol(str2double(signal_wavelength(app)), str2double(get_signal_wavelength(app)), 0.5,'DataScale',1)~=1 )%%|| i<100
            %     if(str2double(signal_wavelength(app))>str2double(get_signal_wavelength(app)))%% la valeur actuelle est plus grande que la valeur désirée
            %         step_lyot_dec2(app);
            %     else
            %         step_lyot_inc2(app);
            %     end%if
            %     app.AnswerTextArea.Value=strcat('Wavelength (current)= ',signal_wavelength(app),'\r\n');
            %     % app.LyotangleText.Value=set_lyot(app);
            %     %  i=i+1;
            % 
            % end%while
            % i=0;
            % while (ismembertol(str2double(signal_wavelength(app)), str2double(get_signal_wavelength(app)), 0.1,'DataScale', 1)~=1 )%%|| i<100
            %     if(str2double(signal_wavelength(app))>str2double(get_signal_wavelength(app)))%% la valeur actuelle est plus grande que la valeur désirée
            %         step_lyot_dec1(app);
            %     else
            %         step_lyot_inc1(app);
            %     end%if
            %     app.AnswerTextArea.Value=strcat('Wavelength (current)= ',signal_wavelength(app),'\r\n');
            %     % app.LyotangleText.Value=set_lyot(app);
            %     %  i=i+1;
            % end%while
            app.Step8Lamp.Color=[0,1,0];

         %   pause(1);
            %9 - Start/stop automatic maximization of the output power (uses cavity position actuator)
            max_power_lyot(app);
            lyot_busy=get_cavity_busy(app);
            while strcmp(lyot_busy,'TRUE')
                lyot_busy=get_lyot_busy(app);
                app.LyotangleText.FontColor=[1,0,0];
            end
            app.LyotangleText.FontColor=[0,1,0];
            app.Step9Lamp.Color=[0,1,0];
         %   pause(1);
            % % % 10 - Tune Lyot angle to have the expected wavelength
            % % while (ismembertol(str2double(signal_wavelength(app)), str2double(get_signal_wavelength(app)), 1,'DataScale',1)~=1)
            % %     if(str2double(signal_wavelength(app))>str2double(get_signal_wavelength(app)))%% la valeur actuelle est plus grande que la valeur désirée
            % %         step_lyot_dec2(app);
            % %     else
            % %         step_lyot_inc2(app);
            % %     end%if
            % %     app.AnswerTextArea.Value=strcat('Wavelength (current)= ',signal_wavelength(app),'\r\n');
            % %     app.LyotangleText.Value=set_lyot(app);
            % % end%while
            % %
            % % while (ismembertol(str2double(signal_wavelength(app)), str2double(get_signal_wavelength(app)), 0.2,'DataScale', 1)~=1)
            % %     if(str2double(signal_wavelength(app))>str2double(get_signal_wavelength(app)))%% la valeur actuelle est plus grande que la valeur désirée
            % %         step_lyot_dec1(app);
            % %     else
            % %         step_lyot_inc1(app);
            % %     end%if
            % %     app.AnswerTextArea.Value=strcat('Wavelength (current)= ',signal_wavelength(app),'\r\n');
            % %     app.LyotangleText.Value=set_lyot(app);
            % % end%while
            % % app.Step10Lamp.Color=[0,1,0];
            % %
            % % % 11 - Correct offset
            % % % app.AnswerTextArea.Value='Step #11';
            % % % correct_cavity_offset(app);
            % % % app.AnswerTextArea.Value='Step #11 -> OK';
            % % app.Step11Lamp.Color=[0,1,0];
            % %
            % 12 - Afficher spectre
            Spectrum_display(app);
            app.Step12Lamp.Color=[0,1,0];
            app.WavelengthnmTextArea.Value=signal_wavelength(app);
            %pause(1);
        end

        % Value changed function: WavelengthnmEditField_2
        function WavelengthnmEditField_2ValueChanged(app, event)
            % 1 - check if we are in the manual mode
            manual = check_manual_mode(app);
            % while strcmp(manual,'FALSE')
            %     set_manual(app);
            %     manual = check_manual_mode(app);
            % end%while
            app.out.writeBytes(sprintf('automatic=TRUE\r\n'));
            app.out.flush();
            app.ManualLamp.Color=[0,1,0];
            pause(2);
            % 2 - check if the pump shutter is open
            pump_open = check_pump_open(app);
            while strcmp(pump_open,'FALSE')
                set_pumpopen(app);
                pump_open = check_pump_open(app);
            end%while
            app.PumpLamp.Color=[0,1,0];
            pause(2);

            % 3 - check if the state is idle
            app.IdleLamp.Color=[1,0,0];
            idle = check_status_idle(app);
            while strcmp(idle,'FALSE')
                set_pumpopen(app);
                idle = check_status_idle(app);
            end%while
            app.IdleLamp.Color=[0,1,0];
            app.Step4Lamp.Color=[0,1,0];

            % 4 - set the wavelength
            set_signal_wavelength(app,app.WavelengthnmEditField_2.Value);
            pause(2);
            app.out.writeBytes(sprintf('automatic=TRUE\r\n'));
            app.out.flush();
            pause(5);
            app.AnswerTextArea.Value='TEST ...';
            % 4 - display spectrum
            Spectrum_display(app);
            app.Step12Lamp.Color=[0,1,0];
            app.WavelengthnmTextArea.Value=signal_wavelength(app);
        end

        % Close request function: APEGUISM14UIFigure
        function APEGUISM14UIFigureCloseRequest(app, event)
            app.out.close();
            app.in.close();
            app.tcp_socket.close();
            app.AnswerTextArea.Value = 'Application stopped!';
            pause(5);
            delete(app);
        end

        % Button pushed function: CavitylengthInc
        function CavitylengthIncButtonPushed(app, event)
            sub_inc_cavity(app);
            app.CavitylengthText.Value=(get_cavity_pos(app));
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'CavitylengthInc';
            app.CavitylengthSetEditField.Value=str2double(set_cavity(app));

        end

        % Button pushed function: CavitylengthDec
        function CavitylengthDecButtonPushed(app, event)
            sub_dec_cavity(app);
            app.CavitylengthText.Value=(get_cavity_pos(app));
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'CavitylengthDec';
            app.CavitylengthSetEditField.Value=str2double(set_cavity(app));
        end

        % Button pushed function: CavitylengthDecPlus
        function CavitylengthDecPlusButtonPushed(app, event)
            full_dec_cavity(app);
            app.CavitylengthText.Value=(get_cavity_pos(app));
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'CavitylengthDecPlus';
            app.CavitylengthSetEditField.Value=str2double(set_cavity(app));
        end

        % Button pushed function: CavitylengthIncPlus
        function CavitylengthIncPlusButtonPushed(app, event)
            full_inc_cavity(app);
            app.CavitylengthText.Value=(get_cavity_pos(app));
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'CavitylengthIncPlus';
            app.CavitylengthSetEditField.Value=str2double(set_cavity(app));
        end

        % Button pushed function: LyotangleInc
        function LyotangleIncButtonPushed(app, event)
            step_lyot_inc1(app);
            set_lyot(app);
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'step_lyot_inc1';
        end

        % Button pushed function: LyotangleIncPlus
        function LyotangleIncPlusButtonPushed(app, event)
            step_lyot_inc2(app);
            set_lyot(app);
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'step_lyot_inc2';
        end

        % Button pushed function: LyotangleDec
        function LyotangleDecButtonPushed(app, event)
            step_lyot_dec1(app);
            set_lyot(app);
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'step_lyot_dec1';
        end

        % Button pushed function: LyotangleDecPlus
        function LyotangleDecPlusButtonPushed(app, event)
            step_lyot_dec2(app);
            set_lyot(app);
            Spectrum_display(app);
            app.AnswerTextArea.Value = 'step_lyot_dec2';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            app.AnswerTextArea.Value='LOOP TUNING\r\n';
            Data=zeros(6,app.stepsEditField.Value);
            
            j=1;
            for i= app.MinEditField.Value:(app.MaxEditField.Value-app.MinEditField.Value)/app.stepsEditField.Value:app.MaxEditField.Value
                app.WavelengthnmEditField.Value=i;
                TuneButtonPushed(app);
                Data(1,j)=str2double(signal_wavelength(app));
                Data(2,j)=str2double(bandwidth(app));
                Data(3,j)=str2double(signal_power(app));
                Data(4,j)=str2double(get_cavity(app));
                Data(5,j)=str2double(set_lyot(app));
                Data(6,j)=str2double(get_measuredxtaltemperature_lambda(app));
                spectrum(:,j)=Spectrum_display(app);
                app.Gauge.Value=j;
                j=j+1;
            end%for

            clear i j
            parts = strsplit(pwd, filesep);
            parent_path = strjoin(parts(1:end-1), filesep);
            output_path=[parent_path, filesep,'Results'];
            mkdir(output_path);
            filename = fullfile([output_path,filesep,'Output.mat']);
            save(filename);
            Save_plot(app,Data,output_path,spectrum);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.APEGUISM14UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {746, 746, 746};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {746, 746};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x', 574};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.APEGUISM14UIFigure = uifigure('Visible', 'off');
            app.APEGUISM14UIFigure.Name = 'APE GUI SM 1.4';
            app.APEGUISM14UIFigure.CloseRequestFcn = createCallbackFcn(app, @APEGUISM14UIFigureCloseRequest, true);
            app.APEGUISM14UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.APEGUISM14UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TCPIPcommunicationPanel = uipanel(app.LeftPanel);
            app.TCPIPcommunicationPanel.Title = 'TCP/IP communication';
            app.APEIPaddressEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.APEIPaddressEditFieldLabel.Text = 'APE IP address';
            app.APEIPaddressEditField = uieditfield(app.TCPIPcommunicationPanel, 'text');
            app.APEIPaddressEditField.ValueChangedFcn = createCallbackFcn(app, @APEIPaddressEditFieldValueChanged, true);
            app.APEIPaddressEditField.Value = '10.48.14.22';
            app.PortEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.TCPIPcommunicationPanel, 'numeric');
            app.PortEditField.Limits = [0 Inf];
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 51123;
            app.StartcommunicationButton = uibutton(app.TCPIPcommunicationPanel, 'push');
            app.StartcommunicationButton.ButtonPushedFcn = createCallbackFcn(app, @StartcommunicationButtonPushed, true);
            app.StartcommunicationButton.Text = 'Start communication';
            app.StopcommunicationButton = uibutton(app.TCPIPcommunicationPanel, 'push');
            app.StopcommunicationButton.ButtonPushedFcn = createCallbackFcn(app, @StopcommunicationButtonPushed, true);
            app.StopcommunicationButton.Text = 'Stop communication';
            app.CommandsPanel = uipanel(app.LeftPanel);
            app.CommandsPanel.Title = 'Commands';
            app.CommandListBoxLabel = uilabel(app.CommandsPanel);
            app.CommandListBoxLabel.Text = 'Command';
            app.CommandListBox = uilistbox(app.CommandsPanel);
            app.CommandListBox.Items = {'-----------------------------------------------', 'spectrum?', '-----------------------------------------------', 'set_cavity?', 'set_cavity_fullsteps?', 'set_cavity_substeps?', 'cavity_position?', 'cavity_busy?', '-----------------------------------------------', 'set_lyot?', 'set_lyot_steplength1?', 'set_lyot_steplength2?', 'lyot_position?', 'lyot_busy?', '-----------------------------------------------', 'set_xtal_temperature?', 'xtal_temperature?', 'xtal_temp_stabilized?', 'thermo_status?', '-----------------------------------------------', 'set_signal_wavelength?', 'automatic?', 'pump_open?', 'state?'};
            app.CommandListBox.ValueChangedFcn = createCallbackFcn(app, @CommandListBoxValueChanged, true);
            app.CommandListBox.Value = 'spectrum?';
            app.AnswerTextAreaLabel = uilabel(app.CommandsPanel);
            app.AnswerTextAreaLabel.Text = 'Answer';
            app.AnswerTextArea = uitextarea(app.CommandsPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.StatusPanel = uipanel(app.CenterPanel);
            app.StatusPanel.Title = 'Status';
            app.TemperatureGauge = uigauge(app.StatusPanel, 'semicircular');
            app.TemperatureGauge.Limits = [0 50];
            app.TempLabel = uilabel(app.StatusPanel);
            app.TempLabel.Text = 'Temp';
            app.HumidityGauge = uigauge(app.StatusPanel, 'semicircular');
            app.HumidityGauge.Limits = [0 50];
            app.HumidityGauge.ScaleDirection = 'counterclockwise';
            app.HumidityLabel = uilabel(app.StatusPanel);
            app.HumidityLabel.Text = 'Humidity';
            app.StatusLampLabel = uilabel(app.StatusPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.SpectruminfoPanel = uipanel(app.CenterPanel);
            app.SpectruminfoPanel.Title = 'Spectrum info';
            app.UIAxes = uiaxes(app.SpectruminfoPanel);
            title(app.UIAxes, 'Spectrum')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (a.u)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [750 900];
            app.XminEditFieldLabel = uilabel(app.SpectruminfoPanel);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.SpectruminfoPanel, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.XminEditField.Value = 750;
            app.XmaxEditFieldLabel = uilabel(app.SpectruminfoPanel);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.SpectruminfoPanel, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxEditFieldValueChanged, true);
            app.XmaxEditField.Value = 900;
            app.WavelengthnmTextAreaLabel = uilabel(app.SpectruminfoPanel);
            app.WavelengthnmTextAreaLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmTextArea = uitextarea(app.SpectruminfoPanel);
            app.FWHMnmTextAreaLabel = uilabel(app.SpectruminfoPanel);
            app.FWHMnmTextAreaLabel.Text = 'FWHM (nm)';
            app.FWHMnmTextArea = uitextarea(app.SpectruminfoPanel);
            app.PowermWTextAreaLabel = uilabel(app.SpectruminfoPanel);
            app.PowermWTextAreaLabel.Text = 'Power (mW)';
            app.PowermWTextArea = uitextarea(app.SpectruminfoPanel);
            app.WevlengthChangePanel = uipanel(app.CenterPanel);
            app.WevlengthChangePanel.Title = 'Wevlength Change';
            app.WavelengthnmEditField_2Label = uilabel(app.WevlengthChangePanel);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField_2 = uieditfield(app.WevlengthChangePanel, 'numeric');
            app.WavelengthnmEditField_2.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditField_2ValueChanged, true);
            app.WavelengthnmEditField_2.Value = 800;
            app.RightPanel = uipanel(app.GridLayout);
            app.AdjustmentsPanel = uipanel(app.RightPanel);
            app.AdjustmentsPanel.Title = 'Adjustments';
            app.CavitylengthPanel = uipanel(app.AdjustmentsPanel);
            app.CavitylengthPanel.Title = 'Cavity length';
            app.CavitylengthDecPlus = uibutton(app.CavitylengthPanel, 'push');
            app.CavitylengthDecPlus.ButtonPushedFcn = createCallbackFcn(app, @CavitylengthDecPlusButtonPushed, true);
            app.CavitylengthDecPlus.Text = '<<';
            app.CavitylengthDec = uibutton(app.CavitylengthPanel, 'push');
            app.CavitylengthDec.ButtonPushedFcn = createCallbackFcn(app, @CavitylengthDecButtonPushed, true);
            app.CavitylengthDec.Text = '<';
            app.CavitylengthInc = uibutton(app.CavitylengthPanel, 'push');
            app.CavitylengthInc.ButtonPushedFcn = createCallbackFcn(app, @CavitylengthIncButtonPushed, true);
            app.CavitylengthInc.Text = '>';
            app.CavitylengthIncPlus = uibutton(app.CavitylengthPanel, 'push');
            app.CavitylengthIncPlus.ButtonPushedFcn = createCallbackFcn(app, @CavitylengthIncPlusButtonPushed, true);
            app.CavitylengthIncPlus.Text = '>>';
            app.Label = uilabel(app.CavitylengthPanel);
            app.CavitylengthText = uitextarea(app.CavitylengthPanel);
            app.SetEditFieldLabel = uilabel(app.CavitylengthPanel);
            app.SetEditFieldLabel.Text = 'Set';
            app.CavitylengthSetEditField = uieditfield(app.CavitylengthPanel, 'numeric');
            app.CavitylengthSetEditField.ValueDisplayFormat = '%.0f';
            app.CavitylengthSetEditField.ValueChangedFcn = createCallbackFcn(app, @CavitylengthSetEditFieldValueChanged, true);
            app.LyotanglePanel = uipanel(app.AdjustmentsPanel);
            app.LyotanglePanel.Title = 'Lyot angle';
            app.LyotangleDecPlus = uibutton(app.LyotanglePanel, 'push');
            app.LyotangleDecPlus.ButtonPushedFcn = createCallbackFcn(app, @LyotangleDecPlusButtonPushed, true);
            app.LyotangleDecPlus.Text = '<<';
            app.LyotangleDec = uibutton(app.LyotanglePanel, 'push');
            app.LyotangleDec.ButtonPushedFcn = createCallbackFcn(app, @LyotangleDecButtonPushed, true);
            app.LyotangleDec.Text = '<';
            app.LyotangleInc = uibutton(app.LyotanglePanel, 'push');
            app.LyotangleInc.ButtonPushedFcn = createCallbackFcn(app, @LyotangleIncButtonPushed, true);
            app.LyotangleInc.Text = '>';
            app.LyotangleIncPlus = uibutton(app.LyotanglePanel, 'push');
            app.LyotangleIncPlus.ButtonPushedFcn = createCallbackFcn(app, @LyotangleIncPlusButtonPushed, true);
            app.LyotangleIncPlus.Text = '>>';
            app.Label_2 = uilabel(app.LyotanglePanel);
            app.Label_2.Text = '';
            app.LyotangleText = uitextarea(app.LyotanglePanel);
            app.SetEditField_2Label = uilabel(app.LyotanglePanel);
            app.SetEditField_2Label.Text = 'Set';
            app.LyotangleSetEditField = uieditfield(app.LyotanglePanel, 'numeric');
            app.LyotangleSetEditField.ValueDisplayFormat = '%.0f';
            app.XtaltemperaturePanel = uipanel(app.AdjustmentsPanel);
            app.XtaltemperaturePanel.Title = 'Xtal temperature';
            app.XtaltemperaturePlus = uibutton(app.XtaltemperaturePanel, 'push');
            app.XtaltemperaturePlus.Text = '<<';
            app.XtaltemperatureDec = uibutton(app.XtaltemperaturePanel, 'push');
            app.XtaltemperatureDec.Text = '<';
            app.XtaltemperatureInc = uibutton(app.XtaltemperaturePanel, 'push');
            app.XtaltemperatureInc.Text = '>';
            app.XtaltemperatureIncPlus = uibutton(app.XtaltemperaturePanel, 'push');
            app.XtaltemperatureIncPlus.Text = '>>';
            app.Label_3 = uilabel(app.XtaltemperaturePanel);
            app.Label_3.Text = '';
            app.XtaltemperatureText = uitextarea(app.XtaltemperaturePanel);
            app.SetEditField_3Label = uilabel(app.XtaltemperaturePanel);
            app.SetEditField_3Label.Text = 'Set';
            app.XtaltemperatureSetEditField = uieditfield(app.XtaltemperaturePanel, 'numeric');
            app.TuningPanel = uipanel(app.RightPanel);
            app.TuningPanel.Title = 'Tuning';
            app.WavelengthnmEditFieldLabel = uilabel(app.TuningPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.TuningPanel, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.WavelengthnmEditField.Value = 800;
            app.TuneButton = uibutton(app.TuningPanel, 'push');
            app.TuneButton.ButtonPushedFcn = createCallbackFcn(app, @TuneButtonPushed, true);
            app.TuneButton.Text = 'Tune!';
            app.ManualLampLabel = uilabel(app.TuningPanel);
            app.ManualLampLabel.Text = 'Manual?';
            app.ManualLamp = uilamp(app.TuningPanel);
            app.PumpLampLabel = uilabel(app.TuningPanel);
            app.PumpLampLabel.Text = 'Pump?';
            app.PumpLamp = uilamp(app.TuningPanel);
            app.IdleLampLabel = uilabel(app.TuningPanel);
            app.IdleLampLabel.Text = 'Idle?';
            app.IdleLamp = uilamp(app.TuningPanel);
            app.XtalstabLampLabel = uilabel(app.TuningPanel);
            app.XtalstabLampLabel.Text = 'Xtal stab?';
            app.XtalstabLamp = uilamp(app.TuningPanel);
            app.Step1LampLabel = uilabel(app.TuningPanel);
            app.Step1LampLabel.Text = 'Step #1';
            app.Step1Lamp = uilamp(app.TuningPanel);
            app.Step2LampLabel = uilabel(app.TuningPanel);
            app.Step2LampLabel.Text = 'Step #2';
            app.Step2Lamp = uilamp(app.TuningPanel);
            app.Step3LampLabel = uilabel(app.TuningPanel);
            app.Step3LampLabel.Text = 'Step #3';
            app.Step3Lamp = uilamp(app.TuningPanel);
            app.Step4LampLabel = uilabel(app.TuningPanel);
            app.Step4LampLabel.Text = 'Step #4';
            app.Step4Lamp = uilamp(app.TuningPanel);
            app.Step5Lamp_2Label = uilabel(app.TuningPanel);
            app.Step5Lamp_2Label.Text = 'Step #5';
            app.Step5Lamp = uilamp(app.TuningPanel);
            app.Step6Lamp_2Label = uilabel(app.TuningPanel);
            app.Step6Lamp_2Label.Text = 'Step #6';
            app.Step6Lamp = uilamp(app.TuningPanel);
            app.Step7Lamp_2Label = uilabel(app.TuningPanel);
            app.Step7Lamp_2Label.Text = 'Step #7';
            app.Step7Lamp = uilamp(app.TuningPanel);
            app.Step8Lamp_2Label = uilabel(app.TuningPanel);
            app.Step8Lamp_2Label.Text = 'Step #8';
            app.Step8Lamp = uilamp(app.TuningPanel);
            app.Step9Lamp_2Label = uilabel(app.TuningPanel);
            app.Step9Lamp_2Label.Text = 'Step #9';
            app.Step9Lamp = uilamp(app.TuningPanel);
            app.Step10Lamp_2Label = uilabel(app.TuningPanel);
            app.Step10Lamp_2Label.Text = 'Step #10';
            app.Step10Lamp = uilamp(app.TuningPanel);
            app.Step11Lamp_2Label = uilabel(app.TuningPanel);
            app.Step11Lamp_2Label.Text = 'Step #11';
            app.Step11Lamp = uilamp(app.TuningPanel);
            app.Step12Lamp_2Label = uilabel(app.TuningPanel);
            app.Step12Lamp_2Label.Text = 'Step #12';
            app.Step12Lamp = uilamp(app.TuningPanel);
            app.Step13Lamp_2Label = uilabel(app.TuningPanel);
            app.Step13Lamp_2Label.Text = 'Step #13';
            app.Step13Lamp = uilamp(app.TuningPanel);
            app.Step14Lamp_2Label = uilabel(app.TuningPanel);
            app.Step14Lamp_2Label.Text = 'Step #14';
            app.Step14Lamp = uilamp(app.TuningPanel);
            app.Step15LampLabel = uilabel(app.TuningPanel);
            app.Step15LampLabel.Text = 'Step #15';
            app.Step15Lamp = uilamp(app.TuningPanel);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-if-marseille_fond_transparent.png');
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'APE.png');
            app.TuneRecordPanel = uipanel(app.RightPanel);
            app.TuneRecordPanel.Title = 'Tune & Record';
            app.MinEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.MinEditField.Value = 780;
            app.MaxEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.MaxEditField.Value = 820;
            app.stepsEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.stepsEditFieldLabel.Text = '# steps';
            app.stepsEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.stepsEditField.Value = 40;
            app.Button = uibutton(app.TuneRecordPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GaugeLabel = uilabel(app.TuneRecordPanel);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.TuneRecordPanel, 'linear');
            app.Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            % Show the figure after all components are created
            app.APEGUISM14UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APE_GUI_SM_v1_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APEGUISM14UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APEGUISM14UIFigure)
        end
    end
end