classdef APE_OPO_Tuning_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StatusPanel                 matlab.ui.container.Panel
        SavetableButton             matlab.ui.control.Button
        ResettableButton            matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        GetcurrentvaluesButton      matlab.ui.control.Button
        StatusLamp                  matlab.ui.control.Lamp
        StatusLampLabel             matlab.ui.control.Label
        HumidityGaugeLabel          matlab.ui.control.Label
        HumidityGauge               matlab.ui.control.SemicircularGauge
        TempLabel                   matlab.ui.control.Label
        TemperatureGauge            matlab.ui.control.SemicircularGauge
        TCPIPcommunicationPanel     matlab.ui.container.Panel
        PortEditField               matlab.ui.control.NumericEditField
        PortEditFieldLabel          matlab.ui.control.Label
        APEIPaddressEditField       matlab.ui.control.EditField
        APEIPaddressEditFieldLabel  matlab.ui.control.Label
        TuneRecordPanel             matlab.ui.container.Panel
        STOPButton                  matlab.ui.control.Button
        repetitionsEditField        matlab.ui.control.NumericEditField
        repetitionsEditFieldLabel   matlab.ui.control.Label
        TuneButton                  matlab.ui.control.Button
        stepsEditField              matlab.ui.control.NumericEditField
        stepsEditFieldLabel         matlab.ui.control.Label
        MaxEditField                matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        MinEditField                matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        address string = '10.48.14.22'; % IP of the client
        port double {mustBePositive(port), mustBeInteger(port), mustBeFinite(port)} = 51123; % Port of the client
        tcp_socket
        in
        out

        Lyot_angle                          % Current Lyot actuator position
        Cavity_length                       % Current cavity actuator position
        XTal_temp                           % Current Crystal temperature
        Signal_Wavelength                   % Current signal wavelength
        Signal_FWHM                         % Current signal FWHM
        Signal_Power                        % Current signal power
        date                                % Current datetime

        wavelength2scan     =[791,796,6, 2] % wavelenth min/max/#steps/#repetitions
        stop                = 'false'       % stop button
   end
    methods (Access = private)
        %% Initialization of the TCP/IP communication with the OPO laser
        function Init_communication(app)
            % import java tcp socket libraries
            import java.io.*;
            import java.net.*;
            
            %open socket connection to the remote target
            app.tcp_socket = Socket(app.address,app.port);
            %create socket-writer and -reader
            app.in =  DataInputStream(app.tcp_socket.getInputStream);
            app.out = DataOutputStream(app.tcp_socket.getOutputStream) ;
            pause(1); % necessary to wait before sending any query to the laser!
        end

        %% Get current status of the laser
        function get_current_values(app)
            get_temp(app);
            get_humidity(app);
            get_state(app);
            get_Lyot(app);
            get_Cavity_Length(app);
            get_XTal_temp(app);
            get_Signal_Wavelength(app);
            get_Signal_FWHM(app);
            get_Signal_Power(app);
            fill_table(app);
        end

        %% Specific function for the table
        function fill_table(app)
            app.UITable.ColumnFormat = {'numeric','numeric','numeric','numeric','numeric','numeric'};
            app.UITable.Data(end+1,:)=[app.Signal_Wavelength,app.Signal_FWHM,app.Signal_Power,app.Lyot_angle,app.Cavity_length,app.XTal_temp];
        end%function
        function reset_table(app)
              app.UITable.Data = []; %% reset the table
        end%function        
        function savetable(app)
            table_data=app.UITable.Data;
            d = datetime();
            d.Format='yyMMdd_hhmmss';
            save([char(d) '_data.mat'],'table_data');
        end%function

        %% Specific functions to get the status of the laser
        function get_temp(app)
            app.out.writeBytes(sprintf('temperature?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.TemperatureGauge.Value=str2double(strrep(str,',','.'));
        end%function
        function get_humidity(app)
            app.out.writeBytes(sprintf('humidity?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.HumidityGauge.Value=str2double(strrep(str,',','.'));
        end%function
        function str=get_state(app)
            app.out.writeBytes(sprintf('state?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            if contains(str,'error')
                app.StatusLamp.Color=[1,0,0];
            else
                app.StatusLamp.Color=[0,1,0];
            end%if
        end%function
        function get_Lyot(app)
            app.out.writeBytes(sprintf('lyot_position?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Lyot_angle = str2double(str);
        end%function
        function get_Cavity_Length(app)
            app.out.writeBytes(sprintf('cavity_position?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Cavity_length = str2double(str);
        end%function
        function get_XTal_temp(app)
            app.out.writeBytes(sprintf('xtal_temperature?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.XTal_temp = str2double(str);
        end%function
        function get_Signal_Wavelength(app)
            app.out.writeBytes(sprintf('signal_wavelength?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Signal_Wavelength = str2double(str);
        end%function
        function get_Signal_FWHM(app)
            app.out.writeBytes(sprintf('bandwidth?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Signal_FWHM = str2double(str);
        end%function
        function get_Signal_Power(app)
            app.out.writeBytes(sprintf('signal_power?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Signal_Power = str2double(str);
        end%function
        function set_automatic(app,state)
           app.out.writeBytes(sprintf(['automatic=' state '\r\n']));
           app.out.flush();
        end%function
        function set_wavelength(app,value)
            app.out.writeBytes(sprintf(strcat('set_signal_wavelength=',string(value),'\r\n')));
            app.out.flush();          
        end%function


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            Init_communication(app);
            get_current_values(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.out.close();
            app.in.close();
            app.tcp_socket.close();
            delete(app);
        end

        % Value changed function: APEIPaddressEditField
        function APEIPaddressEditFieldValueChanged(app, event)
            app.address = app.APEIPaddressEditField.Value;
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            app.port = app.PortEditField.Value;
        end

        % Button pushed function: GetcurrentvaluesButton
        function GetcurrentvaluesButtonPushed(app, event)
            get_current_values(app);
        end

        % Button pushed function: ResettableButton
        function ResettableButtonPushed(app, event)
            reset_table(app);
        end

        % Button pushed function: SavetableButton
        function SavetableButtonPushed(app, event)
            savetable(app);
        end

        % Button pushed function: TuneButton
        function TuneButtonPushed(app, event)

            %% reset the table
            reset_table(app);
            
            %% set the laser mode to automatic tuning
            set_automatic(app,'TRUE');

            %% Loop over the number of repetitions
            for rep = 1:app.wavelength2scan(4)
                i=0;
                %% Loop over the wavelength to scan
                for wav = app.wavelength2scan(1):(app.wavelength2scan(2)-app.wavelength2scan(1))/(app.wavelength2scan(3)-1):app.wavelength2scan(2)
                    i=i+1;
                    if strcmp(app.stop,'false') % stop button has not been pressed
                        % set the expected wavelength
                        set_wavelength(app,wav);

                        % Status pour savoir quand c'est fini
                        status = 0;
                        while (status == 0 ) %% wait until the wavelength is tuned or stop button pressed
                            str = get_state(app);
                            if strcmp(str,'tuning OK')|| strcmp(app.stop,'true')
                                status = 1;
                            end%if
                        end
                        % get the cavity parameters
                        get_current_values(app);
                    end%if
                end%for
            end%for

            %% save the table of tuning parameters
            savetable(app);

            %% set the laser mode to manual (automatic = 'FALSE')
            set_automatic(app,'FALSE');
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.wavelength2scan(1) = app.MinEditField.Value;
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.wavelength2scan(2) = app.MaxEditField.Value;
        end

        % Value changed function: stepsEditField
        function stepsEditFieldValueChanged(app, event)
            app.wavelength2scan(3) = app.stepsEditField.Value;            
        end

        % Value changed function: repetitionsEditField
        function repetitionsEditFieldValueChanged(app, event)
            app.wavelength2scan(4) = app.repetitionsEditField.Value;            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop = 'true';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TuneRecordPanel = uipanel(app.UIFigure);
            app.TuneRecordPanel.Title = 'Tune & Record';
            app.MinEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MinEditField.Value = 791;
            app.MaxEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 796;
            app.stepsEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.stepsEditFieldLabel.Text = '# steps';
            app.stepsEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.stepsEditField.ValueChangedFcn = createCallbackFcn(app, @stepsEditFieldValueChanged, true);
            app.stepsEditField.Value = 6;
            app.TuneButton = uibutton(app.TuneRecordPanel, 'push');
            app.TuneButton.ButtonPushedFcn = createCallbackFcn(app, @TuneButtonPushed, true);
            app.TuneButton.Text = 'Tune!';
            app.repetitionsEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.repetitionsEditFieldLabel.Text = '#  repetitions';
            app.repetitionsEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.repetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @repetitionsEditFieldValueChanged, true);
            app.repetitionsEditField.Value = 3;
            app.STOPButton = uibutton(app.TuneRecordPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP !!!';
            app.TCPIPcommunicationPanel = uipanel(app.UIFigure);
            app.TCPIPcommunicationPanel.Title = 'TCP/IP communication';
            app.APEIPaddressEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.APEIPaddressEditFieldLabel.Text = 'APE IP address';
            app.APEIPaddressEditField = uieditfield(app.TCPIPcommunicationPanel, 'text');
            app.APEIPaddressEditField.ValueChangedFcn = createCallbackFcn(app, @APEIPaddressEditFieldValueChanged, true);
            app.APEIPaddressEditField.Value = '10.48.14.22';
            app.PortEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.TCPIPcommunicationPanel, 'numeric');
            app.PortEditField.Limits = [0 Inf];
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 51123;
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.TemperatureGauge = uigauge(app.StatusPanel, 'semicircular');
            app.TemperatureGauge.Limits = [0 50];
            app.TempLabel = uilabel(app.StatusPanel);
            app.TempLabel.Text = 'Temp';
            app.HumidityGauge = uigauge(app.StatusPanel, 'semicircular');
            app.HumidityGauge.Limits = [0 50];
            app.HumidityGauge.ScaleDirection = 'counterclockwise';
            app.HumidityGaugeLabel = uilabel(app.StatusPanel);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.StatusLampLabel = uilabel(app.StatusPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.GetcurrentvaluesButton = uibutton(app.StatusPanel, 'push');
            app.GetcurrentvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @GetcurrentvaluesButtonPushed, true);
            app.GetcurrentvaluesButton.Text = 'Get current values';
            app.UITable = uitable(app.StatusPanel);
            app.UITable.ColumnName = {'Wavelength'; 'FWHM'; 'Power'; 'Lyot angle'; 'Cavity length'; 'Xtal temp'};
            app.UITable.RowName = {};
            app.ResettableButton = uibutton(app.StatusPanel, 'push');
            app.ResettableButton.ButtonPushedFcn = createCallbackFcn(app, @ResettableButtonPushed, true);
            app.ResettableButton.Text = 'Reset table';
            app.SavetableButton = uibutton(app.StatusPanel, 'push');
            app.SavetableButton.ButtonPushedFcn = createCallbackFcn(app, @SavetableButtonPushed, true);
            app.SavetableButton.Text = 'Save table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APE_OPO_Tuning_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end