classdef APE_OPO_Tuning_v1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StatusPanel                  matlab.ui.container.Panel
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        HumidityGaugeLabel           matlab.ui.control.Label
        HumidityGauge                matlab.ui.control.SemicircularGauge
        TempLabel                    matlab.ui.control.Label
        TemperatureGauge             matlab.ui.control.SemicircularGauge
        TCPIPcommunicationPanel      matlab.ui.container.Panel
        PortEditField                matlab.ui.control.NumericEditField
        PortEditFieldLabel           matlab.ui.control.Label
        APEIPaddressEditField        matlab.ui.control.EditField
        APEIPaddressEditFieldLabel   matlab.ui.control.Label
        TuneRecordPanel              matlab.ui.container.Panel
        STOPtuningButton             matlab.ui.control.Button
        TunefromMintoMaxButton       matlab.ui.control.Button
        GotoMinButton                matlab.ui.control.Button
        repetitionsEditField         matlab.ui.control.NumericEditField
        repetitionsEditFieldLabel    matlab.ui.control.Label
        stepsEditField               matlab.ui.control.NumericEditField
        stepsEditFieldLabel          matlab.ui.control.Label
        MaxEditField                 matlab.ui.control.NumericEditField
        MaxEditFieldLabel            matlab.ui.control.Label
        MinEditField                 matlab.ui.control.NumericEditField
        MinEditFieldLabel            matlab.ui.control.Label
        ParametersPanel              matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        UIAxes_Power                 matlab.ui.control.UIAxes
        UIAxes_FWHM                  matlab.ui.control.UIAxes
        UIAxes_Xtal                  matlab.ui.control.UIAxes
        UIAxes_Cavity                matlab.ui.control.UIAxes
        UIAxes_Lyot                  matlab.ui.control.UIAxes
        TablepanelPanel              matlab.ui.container.Panel
        GotothispositionButton       matlab.ui.control.Button
        SavetableButton              matlab.ui.control.Button
        loadtableButton              matlab.ui.control.Button
        ResettableButton             matlab.ui.control.Button
        GetcurrentvaluesButton       matlab.ui.control.Button
        SelecttheoutputfolderButton  matlab.ui.control.Button
    end


    properties (Access = private)
        address string = '10.48.14.22'; % IP of the client
        port double {mustBePositive(port), mustBeInteger(port), mustBeFinite(port)} = 51123; % Port of the client
        tcp_socket
        in
        out

        Lyot_angle                          % Current Lyot actuator position
        Cavity_length                       % Current cavity actuator position
        XTal_temp                           % Current Crystal temperature
        Signal_Wavelength                   % Current signal wavelength
        Signal_FWHM                         % Current signal FWHM
        Signal_Power                        % Current signal power
        date                                % Current datetime

        wavelength2scan     =[791,796,6, 2] % wavelenth min/max/#steps/#repetitions
        stop                = 'false'       % stop button
        outpath                             % output folder
   end
    methods (Access = private)
        %% Initialization of the TCP/IP communication with the OPO laser
        function Init_communication(app)
            % import java tcp socket libraries
            import java.io.*;
            import java.net.*;
            
            %open socket connection to the remote target
            app.tcp_socket = Socket(app.address,app.port);
            %create socket-writer and -reader
            app.in =  DataInputStream(app.tcp_socket.getInputStream);
            app.out = DataOutputStream(app.tcp_socket.getOutputStream) ;
            pause(1); % necessary to wait before sending any query to the laser!
        end

        %% Get current status of the laser
        function get_current_values(app)
            get_temp(app);
            get_humidity(app);
            get_state(app);
            get_Lyot(app);
            get_Cavity_Length(app);
            get_XTal_temp(app);
            get_Signal_Wavelength(app);
            get_Signal_FWHM(app);
            get_Signal_Power(app);
            fill_table(app);
        end

        %% Specific function for the table
        function fill_table(app)
            app.UITable.ColumnFormat = {'numeric','numeric','numeric','numeric','numeric','numeric'};
            app.UITable.Data(end+1,:)=[app.Signal_Wavelength,app.Signal_FWHM,app.Signal_Power,app.Lyot_angle,app.Cavity_length,app.XTal_temp];
        end%function
        function reset_table(app)
              app.UITable.Data = []; %% reset the table
        end%function        
        function savetable(app)
            table_data=app.UITable.Data;
            d = datetime();
            d.Format='yyMMdd_hhmmss';
            save([app.outpath filesep char(d) '_data.mat'],'table_data');
        end%function
        function plot_table(app)
            plot(app.UIAxes_Lyot,app.UITable.Data(:,1),app.UITable.Data(:,4));
            plot(app.UIAxes_Cavity,app.UITable.Data(:,1),app.UITable.Data(:,5));
            plot(app.UIAxes_Xtal,app.UITable.Data(:,1),app.UITable.Data(:,6));
            plot(app.UIAxes_FWHM,app.UITable.Data(:,1),app.UITable.Data(:,2));
            plot(app.UIAxes_Power,app.UITable.Data(:,1),app.UITable.Data(:,3));            
        end%funciton
        %% Specific functions to get the status of the laser
        function get_temp(app)
            app.out.writeBytes(sprintf('temperature?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.TemperatureGauge.Value=str2double(strrep(str,',','.'));
        end%function
        function get_humidity(app)
            app.out.writeBytes(sprintf('humidity?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.HumidityGauge.Value=str2double(strrep(str,',','.'));
        end%function
        function str=get_state(app)
            app.out.writeBytes(sprintf('state?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            if contains(str,'error')
                app.StatusLamp.Color=[1,0,0];
            else
                app.StatusLamp.Color=[0,1,0];
            end%if
        end%function
        function get_Lyot(app)
            app.out.writeBytes(sprintf('lyot_position?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Lyot_angle = str2double(str);
        end%function
        function get_Cavity_Length(app)
            app.out.writeBytes(sprintf('cavity_position?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Cavity_length = str2double(str);
        end%function
        function get_XTal_temp(app)
            app.out.writeBytes(sprintf('xtal_temperature?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.XTal_temp = str2double(str);
        end%function
        function get_Signal_Wavelength(app)
            app.out.writeBytes(sprintf('signal_wavelength?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Signal_Wavelength = str2double(str);
        end%function
        function get_Signal_FWHM(app)
            app.out.writeBytes(sprintf('bandwidth?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Signal_FWHM = str2double(str);
        end%function
        function get_Signal_Power(app)
            app.out.writeBytes(sprintf('signal_power?\r\n'));
            app.out.flush();
            str=char(app.in.readLine());
            app.Signal_Power = str2double(str);
        end%function
        function set_automatic(app,state)
           app.out.writeBytes(sprintf(['automatic=' state '\r\n']));
           app.out.flush();
        end%function
        function set_wavelength(app,value)
            app.out.writeBytes(sprintf(strcat('set_signal_wavelength=',string(value),'\r\n')));
            app.out.flush();          
        end%function

        %% Specific functions to set parameters of the laser
        function set_lyot(app,value)
            app.out.writeBytes(sprintf(strcat('set_lyot=',string(value),'\r\n')));
            app.out.flush();          
        end%function
        function set_cavity(app,value)
            app.out.writeBytes(sprintf(strcat('set_cavity=',string(value),'\r\n')));
            app.out.flush();          
        end%function
        function set_xtal_temperature(app,value)
            app.out.writeBytes(sprintf(strcat('set_xtal_temperature=',string(value),'\r\n')));
            app.out.flush();
        end%function


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            app.UITable.SelectionType = 'row';
            Init_communication(app);
            get_current_values(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.out.close();
            app.in.close();
            app.tcp_socket.close();
            delete(app);
        end

        % Value changed function: APEIPaddressEditField
        function APEIPaddressEditFieldValueChanged(app, event)
            app.address = app.APEIPaddressEditField.Value;
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            app.port = app.PortEditField.Value;
        end

        % Button pushed function: GetcurrentvaluesButton
        function GetcurrentvaluesButtonPushed(app, event)
            get_current_values(app);
        end

        % Button pushed function: ResettableButton
        function ResettableButtonPushed(app, event)
            reset_table(app);
        end

        % Button pushed function: SavetableButton
        function SavetableButtonPushed(app, event)
            savetable(app);
        end

        % Button pushed function: TunefromMintoMaxButton
        function TunefromMintoMaxButtonPushed(app, event)

            %% reset the table
            reset_table(app);
            
            %% set the laser mode to automatic tuning
            set_automatic(app,'TRUE');

            %% Loop over the number of repetitions
            for rep = 1:app.wavelength2scan(4)
                i=0;
                %% Loop over the wavelength to scan
                for wav = app.wavelength2scan(1):(app.wavelength2scan(2)-app.wavelength2scan(1))/(app.wavelength2scan(3)-1):app.wavelength2scan(2)
                    i=i+1;
                    if strcmp(app.stop,'false') % stop button has not been pressed
                        % set the expected wavelength
                        set_wavelength(app,wav);

                        % Status pour savoir quand c'est fini
                        status = 0;
                        while (status == 0 ) %% wait until the wavelength is tuned or stop button pressed
                            str = get_state(app);
                            if strcmp(str,'tuning OK')|| strcmp(app.stop,'true')
                                status = 1;
                            end%if
                        end
                        % get the cavity parameters
                        get_current_values(app);
                    end%if
                end%for
            end%for

            %% save the table of tuning parameters
            savetable(app);

            %% set the laser mode to manual (automatic = 'FALSE')
            set_automatic(app,'FALSE');
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.wavelength2scan(1) = app.MinEditField.Value;
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.wavelength2scan(2) = app.MaxEditField.Value;
        end

        % Value changed function: stepsEditField
        function stepsEditFieldValueChanged(app, event)
            app.wavelength2scan(3) = app.stepsEditField.Value;            
        end

        % Value changed function: repetitionsEditField
        function repetitionsEditFieldValueChanged(app, event)
            app.wavelength2scan(4) = app.repetitionsEditField.Value;            
        end

        % Button pushed function: STOPtuningButton
        function STOPtuningButtonPushed(app, event)
            app.stop = 'true';
        end

        % Button pushed function: loadtableButton
        function loadtableButtonPushed(app, event)
            [file,location] = uigetfile('*.mat');   % select a .mat file cntaining laser parameters (lambda, FWHM, Power, Lyot, Cavity, Temp)
            load([location file],'table_data');     % load this file
            app.UITable.Data = [];                  % reset the table
            app.UITable.ColumnFormat = {'numeric','numeric','numeric','numeric','numeric','numeric'};
            for i=1:size(table_data,1)              % display the values into the table
                app.UITable.Data(i,:)=table_data(i,:);
            end%if
            plot_table(app);
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            %% get the parameters corresponding to the selected row
            app.Signal_Wavelength   = app.UITable.Data(app.UITable.Selection,1);
            app.Signal_FWHM         = app.UITable.Data(app.UITable.Selection,2);
            app.Signal_Power        = app.UITable.Data(app.UITable.Selection,3);
            app.Lyot_angle          = app.UITable.Data(app.UITable.Selection,4);
            app.Cavity_length       = app.UITable.Data(app.UITable.Selection,5);
            app.XTal_temp           = app.UITable.Data(app.UITable.Selection,6);
        end

        % Button pushed function: GotothispositionButton
        function GotothispositionButtonPushed(app, event)
             %% set the parameters corresponding to the selected row
            set_lyot(app,app.Lyot_angle);
            set_cavity(app,app.Cavity_length);
            set_xtal_temperature(app,app.XTal_temp);            
        end

        % Button pushed function: SelecttheoutputfolderButton
        function SelecttheoutputfolderButtonPushed(app, event)
            app.outpath = uigetdir;
        end

        % Button pushed function: GotoMinButton
        function GotoMinButtonPushed(app, event)
            %% reset the table
            reset_table(app);
            
            %% set the laser mode to automatic tuning
            set_automatic(app,'TRUE');

            % set the expected wavelength
            set_wavelength(app,app.wavelength2scan(1));

            % Status pour savoir quand c'est fini
            status = 0;
            while (status == 0 ) %% wait until the wavelength is tuned or stop button pressed
                str = get_state(app);
                if strcmp(str,'tuning OK')|| strcmp(app.stop,'true')
                    status = 1;
                end%if
            end
            % get the cavity parameters
            get_current_values(app);

            %% set the laser mode to manual (automatic = 'FALSE')
            set_automatic(app,'FALSE');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TablepanelPanel = uipanel(app.UIFigure);
            app.TablepanelPanel.Title = 'Table panel';
            app.SelecttheoutputfolderButton = uibutton(app.TablepanelPanel, 'push');
            app.SelecttheoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheoutputfolderButtonPushed, true);
            app.SelecttheoutputfolderButton.Text = 'Select the output folder';
            app.GetcurrentvaluesButton = uibutton(app.TablepanelPanel, 'push');
            app.GetcurrentvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @GetcurrentvaluesButtonPushed, true);
            app.GetcurrentvaluesButton.Text = 'Get current values';
            app.ResettableButton = uibutton(app.TablepanelPanel, 'push');
            app.ResettableButton.ButtonPushedFcn = createCallbackFcn(app, @ResettableButtonPushed, true);
            app.ResettableButton.Text = 'Reset table';
            app.loadtableButton = uibutton(app.TablepanelPanel, 'push');
            app.loadtableButton.ButtonPushedFcn = createCallbackFcn(app, @loadtableButtonPushed, true);
            app.loadtableButton.Text = 'load table';
            app.SavetableButton = uibutton(app.TablepanelPanel, 'push');
            app.SavetableButton.ButtonPushedFcn = createCallbackFcn(app, @SavetableButtonPushed, true);
            app.SavetableButton.Text = 'Save table';
            app.GotothispositionButton = uibutton(app.TablepanelPanel, 'push');
            app.GotothispositionButton.ButtonPushedFcn = createCallbackFcn(app, @GotothispositionButtonPushed, true);
            app.GotothispositionButton.Text = 'Go to this position';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.UIAxes_Lyot = uiaxes(app.ParametersPanel);
            title(app.UIAxes_Lyot, 'Lyot')
            xlabel(app.UIAxes_Lyot, 'Wavelength (nm)')
            ylabel(app.UIAxes_Lyot, 'Y')
            zlabel(app.UIAxes_Lyot, 'Z')
            app.UIAxes_Cavity = uiaxes(app.ParametersPanel);
            title(app.UIAxes_Cavity, 'Cavity length')
            xlabel(app.UIAxes_Cavity, 'Wavelength (nm)')
            ylabel(app.UIAxes_Cavity, 'Y')
            zlabel(app.UIAxes_Cavity, 'Z')
            app.UIAxes_Xtal = uiaxes(app.ParametersPanel);
            title(app.UIAxes_Xtal, 'Xtal temp')
            xlabel(app.UIAxes_Xtal, 'Wavelength (nm)')
            ylabel(app.UIAxes_Xtal, 'Y')
            zlabel(app.UIAxes_Xtal, 'Z')
            app.UIAxes_FWHM = uiaxes(app.ParametersPanel);
            title(app.UIAxes_FWHM, 'FWHM (nm)')
            xlabel(app.UIAxes_FWHM, 'Wavelength (nm)')
            ylabel(app.UIAxes_FWHM, 'Y')
            zlabel(app.UIAxes_FWHM, 'Z')
            app.UIAxes_Power = uiaxes(app.ParametersPanel);
            title(app.UIAxes_Power, 'Power (mW)')
            xlabel(app.UIAxes_Power, 'Wavelength (nm)')
            ylabel(app.UIAxes_Power, 'Y')
            zlabel(app.UIAxes_Power, 'Z')
            app.UITable = uitable(app.ParametersPanel);
            app.UITable.ColumnName = {'Wavelength'; 'FWHM'; 'Power'; 'Lyot angle'; 'Cavity length'; 'Xtal temp'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.TuneRecordPanel = uipanel(app.UIFigure);
            app.TuneRecordPanel.Title = 'Tune & Record';
            app.MinEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MinEditField.Value = 791;
            app.MaxEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 796;
            app.stepsEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.stepsEditFieldLabel.Text = '# steps';
            app.stepsEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.stepsEditField.ValueChangedFcn = createCallbackFcn(app, @stepsEditFieldValueChanged, true);
            app.stepsEditField.Value = 6;
            app.repetitionsEditFieldLabel = uilabel(app.TuneRecordPanel);
            app.repetitionsEditFieldLabel.Text = '#  repetitions';
            app.repetitionsEditField = uieditfield(app.TuneRecordPanel, 'numeric');
            app.repetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @repetitionsEditFieldValueChanged, true);
            app.repetitionsEditField.Value = 3;
            app.GotoMinButton = uibutton(app.TuneRecordPanel, 'push');
            app.GotoMinButton.ButtonPushedFcn = createCallbackFcn(app, @GotoMinButtonPushed, true);
            app.GotoMinButton.Text = 'Go to Min';
            app.TunefromMintoMaxButton = uibutton(app.TuneRecordPanel, 'push');
            app.TunefromMintoMaxButton.ButtonPushedFcn = createCallbackFcn(app, @TunefromMintoMaxButtonPushed, true);
            app.TunefromMintoMaxButton.Text = 'Tune from Min to Max!';
            app.STOPtuningButton = uibutton(app.TuneRecordPanel, 'push');
            app.STOPtuningButton.ButtonPushedFcn = createCallbackFcn(app, @STOPtuningButtonPushed, true);
            app.STOPtuningButton.Text = 'STOP tuning !!!';
            app.TCPIPcommunicationPanel = uipanel(app.UIFigure);
            app.TCPIPcommunicationPanel.Title = 'TCP/IP communication';
            app.APEIPaddressEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.APEIPaddressEditFieldLabel.Text = 'APE IP address';
            app.APEIPaddressEditField = uieditfield(app.TCPIPcommunicationPanel, 'text');
            app.APEIPaddressEditField.ValueChangedFcn = createCallbackFcn(app, @APEIPaddressEditFieldValueChanged, true);
            app.APEIPaddressEditField.Value = '10.48.14.22';
            app.PortEditFieldLabel = uilabel(app.TCPIPcommunicationPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.TCPIPcommunicationPanel, 'numeric');
            app.PortEditField.Limits = [0 Inf];
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 51123;
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.TemperatureGauge = uigauge(app.StatusPanel, 'semicircular');
            app.TemperatureGauge.Limits = [0 50];
            app.TempLabel = uilabel(app.StatusPanel);
            app.TempLabel.Text = 'Temp';
            app.HumidityGauge = uigauge(app.StatusPanel, 'semicircular');
            app.HumidityGauge.Limits = [0 50];
            app.HumidityGauge.ScaleDirection = 'counterclockwise';
            app.HumidityGaugeLabel = uilabel(app.StatusPanel);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.StatusLampLabel = uilabel(app.StatusPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.StatusPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APE_OPO_Tuning_v1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end