classdef Thorlabs_Kinesis_BrushedMotor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CurrentpositionEditField       matlab.ui.control.NumericEditField
        CurrentpositionEditFieldLabel  matlab.ui.control.Label
        GotoEditField                  matlab.ui.control.NumericEditField
        GotoEditFieldLabel             matlab.ui.control.Label
        THORLABSPRM1MZ8controlledbyaKDC101controllerLabel  matlab.ui.control.Label
        AngleKnob                      matlab.ui.control.Knob
        AngleKnobLabel                 matlab.ui.control.Label
    end


    properties (Access = private)
        motorlist % list of connected motors
        motor1 % first motor
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.motorlist=motor.listdevices;    % List connected devices
            app.motor1=motor;                   % Create a motor object
            app.motor1.connect(app.motorlist{1});% Connect the first device in the list of devices
            app.motor1.home;                    % Home the device

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.motor1.disconnect;
            clear app.motor1;
            delete(app);
        end

        % Value changed function: AngleKnob
        function AngleKnobValueChanged(app, event)
            app.motor1.moveto(app.AngleKnob.Value);       % Move the device to the angle of the knob
            app.CurrentpositionEditField.Value=app.motor1.position; % Get and display the current position
        end

        % Value changed function: GotoEditField
        function GotoEditFieldValueChanged(app, event)
            app.motor1.moveto(app.GotoEditField.Value);  % Move the device
            app.CurrentpositionEditField.Value=app.motor1.position;% Get and display the current position
            app.AngleKnob.Value=app.motor1.position;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AngleKnobLabel = uilabel(app.UIFigure);
            app.AngleKnobLabel.Text = 'Angle';
            app.AngleKnob = uiknob(app.UIFigure, 'continuous');
            app.AngleKnob.Limits = [0 360];
            app.AngleKnob.ValueChangedFcn = createCallbackFcn(app, @AngleKnobValueChanged, true);
            app.THORLABSPRM1MZ8controlledbyaKDC101controllerLabel = uilabel(app.UIFigure);
            app.THORLABSPRM1MZ8controlledbyaKDC101controllerLabel.Text = 'THORLABS PRM1/MZ8 controlled by a KDC101 controller';
            app.GotoEditFieldLabel = uilabel(app.UIFigure);
            app.GotoEditFieldLabel.Text = 'Go to';
            app.GotoEditField = uieditfield(app.UIFigure, 'numeric');
            app.GotoEditField.ValueChangedFcn = createCallbackFcn(app, @GotoEditFieldValueChanged, true);
            app.CurrentpositionEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpositionEditFieldLabel.Text = 'Current position';
            app.CurrentpositionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thorlabs_Kinesis_BrushedMotor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end