classdef Thorlabs_Kinesis_DCMotor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Motorposition025mmSlider       matlab.ui.control.Slider
        Motorposition025mmSliderLabel  matlab.ui.control.Label
        CurrentpositionEditField       matlab.ui.control.NumericEditField
        CurrentpositionEditFieldLabel  matlab.ui.control.Label
        GotoEditField                  matlab.ui.control.NumericEditField
        GotoEditFieldLabel             matlab.ui.control.Label
        THORLABSMTS25MZ8controlledbyaKDC101controllerLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        motorlist % list of connected motors
        motor1 % first motor
        %motor2 % second motor
        % To convert real position in mm into motor units -> POS_units = EncCnt × RealPos
        EncCnt = 34554.96 % For MTS25/M-Z8. See Thorlabs manual APT_Communications_Protocol_v38.pdf, page 39/341
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.motorlist=motor.listdevices;    % List connected devices
            app.motor1=motor;                   % Create a motor object
            app.motor1.connect(app.motorlist{1});% Connect the first device in the list of devices
            uiwait(msgbox("Homing is in progress, please wait!!!","warn","warn"));
            app.motor1.home;                    % Home the device
            uiwait(msgbox("Homing is OK!!!","Homing...","modal"));
            app.CurrentpositionEditField.Value=app.motor1.position;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.motor1.disconnect;
            clear app.motor1;
            delete(app);
        end

        % Value changed function: GotoEditField
        function GotoEditFieldValueChanged(app, event)
            app.motor1.moveto(app.GotoEditField.Value*app.EncCnt);  % Move the device
            app.CurrentpositionEditField.Value=app.motor1.position;% Get and display the current position
            app.Motorposition025mmSlider.Value=app.motor1.position;
        end

        % Value changed function: Motorposition025mmSlider
        function Motorposition025mmSliderValueChanged(app, event)
            app.motor1.moveto(app.Motorposition025mmSlider.Value*app.EncCnt);       % Move the device to the slider position
            app.CurrentpositionEditField.Value=app.motor1.position; % Get and display the current position
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.THORLABSMTS25MZ8controlledbyaKDC101controllerLabel = uilabel(app.UIFigure);
            app.THORLABSMTS25MZ8controlledbyaKDC101controllerLabel.Text = 'THORLABS MTS25/M-Z8 controlled by a KDC101 controller';
            app.GotoEditFieldLabel = uilabel(app.UIFigure);
            app.GotoEditFieldLabel.Text = 'Go to';
            app.GotoEditField = uieditfield(app.UIFigure, 'numeric');
            app.GotoEditField.Limits = [0 25];
            app.GotoEditField.ValueChangedFcn = createCallbackFcn(app, @GotoEditFieldValueChanged, true);
            app.CurrentpositionEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpositionEditFieldLabel.Text = 'Current position';
            app.CurrentpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.Motorposition025mmSliderLabel = uilabel(app.UIFigure);
            app.Motorposition025mmSliderLabel.Text = 'Motor position (0-25 mm)';
            app.Motorposition025mmSlider = uislider(app.UIFigure);
            app.Motorposition025mmSlider.Limits = [0 25];
            app.Motorposition025mmSlider.ValueChangedFcn = createCallbackFcn(app, @Motorposition025mmSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thorlabs_Kinesis_DCMotor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end