classdef Thorlabs_Kinesis_DCMotor_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Motor2isreadyLamp           matlab.ui.control.Lamp
        Motor2isreadyLampLabel      matlab.ui.control.Label
        Motor1isreadyLamp           matlab.ui.control.Lamp
        Motor1isreadyLampLabel      matlab.ui.control.Label
        CurrentpositionM2EditField  matlab.ui.control.NumericEditField
        CurrentpositionM2EditFieldLabel  matlab.ui.control.Label
        GotoM2EditField             matlab.ui.control.NumericEditField
        GotoM2EditFieldLabel        matlab.ui.control.Label
        CurrentpositionM1EditField  matlab.ui.control.NumericEditField
        CurrentpositionM1EditFieldLabel  matlab.ui.control.Label
        GotoM1EditField             matlab.ui.control.NumericEditField
        GotoM1EditFieldLabel        matlab.ui.control.Label
        TwoTHORLABSMTS25MZ8motorscontrolledbyKDC101controllersLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        motorlist % list of connected motors
        motor1 % first motor
        motor2 % second motor
        SN_motor1 string = '27268159' % Serial number
        SN_motor2 string = '27268221' % Serial number

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Motor1isreadyLamp.Color=[1 0 0 ];           % LED in  red
            app.Motor2isreadyLamp.Color=[1 0 0 ];         % LED in  red
            app.motorlist=motor.listdevices;        % List connected devices
            app.motor1=motor;                       % Create a motor object
            Index1 = contains(app.motorlist,app.SN_motor1);
            app.motor1.connect(app.motorlist{Index1});   % Connect the first device in the list of devices
            app.motor2=motor;                       % Create a motor object
            Index2 = contains(app.motorlist,app.SN_motor2); 
            app.motor2.connect(app.motorlist{Index2});   % Connect the second device in the list of devices
            if (~app.motor1.ishomed)
                app.motor1.home;                    % Home the device
            end%if
            if (~app.motor2.ishomed)
                app.motor2.home;                    % Home the device
            end%if
            app.CurrentpositionM1EditField.Value=app.motor1.position;       % Display the current position
            app.CurrentpositionM2EditField.Value=app.motor2.position;       % Display the current position
            app.Motor1isreadyLamp.Color=[~app.motor1.ishomed app.motor1.ishomed 0 ]; % Set the LED color corresponding to the homed status
            app.Motor2isreadyLamp.Color=[~app.motor2.ishomed app.motor2.ishomed 0 ]; % Set the LED color corresponding to the homed status
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.motor1.disconnect;
            app.motor2.disconnect;
            clear app.motor1;
            clear app.motor2;
            delete(app);
        end

        % Value changed function: GotoM1EditField
        function GotoM1EditFieldValueChanged(app, event)
            app.motor1.moveto(app.GotoM1EditField.Value);               % Move the device
            app.CurrentpositionM1EditField.Value=app.motor1.position;   % Get and display the current position
        end

        % Value changed function: GotoM2EditField
        function GotoM2EditFieldValueChanged(app, event)
            app.motor2.moveto(app.GotoM2EditField.Value);               % Move the device
            app.CurrentpositionM2EditField.Value=app.motor2.position;   % Get and display the current position
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TwoTHORLABSMTS25MZ8motorscontrolledbyKDC101controllersLabel = uilabel(app.UIFigure);
            app.TwoTHORLABSMTS25MZ8motorscontrolledbyKDC101controllersLabel.Text = 'Two THORLABS MTS25/M-Z8 motors controlled by KDC101 controllers';
            app.GotoM1EditFieldLabel = uilabel(app.UIFigure);
            app.GotoM1EditFieldLabel.Text = 'Go to (M1)';
            app.GotoM1EditField = uieditfield(app.UIFigure, 'numeric');
            app.GotoM1EditField.Limits = [0 25];
            app.GotoM1EditField.ValueChangedFcn = createCallbackFcn(app, @GotoM1EditFieldValueChanged, true);
            app.CurrentpositionM1EditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpositionM1EditFieldLabel.Text = 'Current position (M1)';
            app.CurrentpositionM1EditField = uieditfield(app.UIFigure, 'numeric');
            app.GotoM2EditFieldLabel = uilabel(app.UIFigure);
            app.GotoM2EditFieldLabel.Text = 'Go to (M2)';
            app.GotoM2EditField = uieditfield(app.UIFigure, 'numeric');
            app.GotoM2EditField.Limits = [0 25];
            app.GotoM2EditField.ValueChangedFcn = createCallbackFcn(app, @GotoM2EditFieldValueChanged, true);
            app.CurrentpositionM2EditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpositionM2EditFieldLabel.Text = 'Current position (M2)';
            app.CurrentpositionM2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Motor1isreadyLampLabel = uilabel(app.UIFigure);
            app.Motor1isreadyLampLabel.Text = 'Motor 1 is ready?';
            app.Motor1isreadyLamp = uilamp(app.UIFigure);
            app.Motor2isreadyLampLabel = uilabel(app.UIFigure);
            app.Motor2isreadyLampLabel.Text = 'Motor 2 is ready?';
            app.Motor2isreadyLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thorlabs_Kinesis_DCMotor_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end