classdef Thorlabs_Kinesis_DCMotor_MoveTable_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LambdanmEditField           matlab.ui.control.NumericEditField
        LambdanmEditFieldLabel      matlab.ui.control.Label
        LoadcalibrationtablematfileButton  matlab.ui.control.Button
        CurrentpositionM2EditField  matlab.ui.control.NumericEditField
        CurrentpositionM2EditFieldLabel  matlab.ui.control.Label
        CurrentpositionM1EditField  matlab.ui.control.NumericEditField
        CurrentpositionM1EditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        motorlist   % list of connected motors
        motor1      % first motor
        motor2      % second motor

        SN_motor1 string = '27268159' % Serial number
        SN_motor2 string = '27268221' % Serial number

        CalibData   % calibration data (lambda|M1Position|M2Position)
        wavelength  % requested wavelength (nm)
        position    % requested motor positions
    end

    
    methods (Access = private)
        
        function Interpolate_Motor_Positions(app)
            if (app.wavelength >= min(app.CalibData.Calibration_Table(:,1)) && app.wavelength <= max(app.CalibData.Calibration_Table(:,1)))
                app.position(1) = interp1(app.CalibData.Calibration_Table(:,1),app.CalibData.Calibration_Table(:,2),app.wavelength,'spline');
                app.position(2) = interp1(app.CalibData.Calibration_Table(:,1),app.CalibData.Calibration_Table(:,3),app.wavelength,'spline');
            else
                warning('lambda is out of range');
            end%if
        end
        
        function MoveMotors(app)
                app.motor1.moveto(app.position(1)); 
                app.CurrentpositionM1EditField.Value=app.motor1.position;       % Display the current position of M1
                app.motor2.moveto(app.position(2)); 
                app.CurrentpositionM2EditField.Value=app.motor2.position;       % Display the current position of M2
        end
        
        function Display_Calib(app)
            plot(app.UIAxes,app.CalibData.Calibration_Table(:,1),app.CalibData.Calibration_Table(:,2),'--b','DisplayName', 'M1');
            hold(app.UIAxes);
            plot(app.UIAxes,app.CalibData.Calibration_Table(:,1),app.CalibData.Calibration_Table(:,3),'--r','DisplayName', 'M2');
            legend(app.UIAxes);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% initialize motors (only if they are connected!)
            [app.motor1,app.motor2]=init_motors(app.SN_motor1,app.SN_motor2);
            if (isempty(app.motor1)||isempty(app.motor2))
                return
            end%if
            app.CurrentpositionM1EditField.Value=app.motor1.position;       % Display the current position of M1
            app.CurrentpositionM2EditField.Value=app.motor2.position;       % Display the current position of M2
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.motor1.disconnect;
            app.motor2.disconnect;
            clear app.motor1;
            clear app.motor2;
            delete(app);
        end

        % Button pushed function: LoadcalibrationtablematfileButton
        function LoadcalibrationtablematfileButtonPushed(app, event)
            [Calibration_File,location]  = uigetfile('*.mat');
            Calibration_File_Path=[location Calibration_File];
            app.CalibData=Load_Calibration_Table(Calibration_File_Path);
            Display_Calib(app);
        end

        % Value changed function: LambdanmEditField
        function LambdanmEditFieldValueChanged(app, event)
            app.wavelength = app.LambdanmEditField.Value;           
            Interpolate_Motor_Positions(app);
            MoveMotors(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calibration curves')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CurrentpositionM1EditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpositionM1EditFieldLabel.Text = 'Current position (M1)';
            app.CurrentpositionM1EditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentpositionM2EditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpositionM2EditFieldLabel.Text = 'Current position (M2)';
            app.CurrentpositionM2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadcalibrationtablematfileButton = uibutton(app.UIFigure, 'push');
            app.LoadcalibrationtablematfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcalibrationtablematfileButtonPushed, true);
            app.LoadcalibrationtablematfileButton.Text = 'Load calibration table (*.mat file)';
            app.LambdanmEditFieldLabel = uilabel(app.UIFigure);
            app.LambdanmEditFieldLabel.Text = 'Lambda (nm)';
            app.LambdanmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LambdanmEditField.ValueChangedFcn = createCallbackFcn(app, @LambdanmEditFieldValueChanged, true);
            app.LambdanmEditField.Value = 792;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thorlabs_Kinesis_DCMotor_MoveTable_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end