classdef Thorlabs_Kinesis_Solenoid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        OpencloseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        sh05 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            shutterlist = shutter.listdevices;
            app.sh05 = shutter    ;
            app.sh05.connect(shutterlist{1}) ;       % Connect the first shutter object to the first device in the list
            app.sh05.operatingmode    ;         % Get current operating mode for sh05
            app.sh05.operatingmode='manual';    % Set operating mode to Manual

        end

        % Button pushed function: OpencloseButton
        function OpencloseButtonPushed(app, event)
            if (strcmp(string(app.sh05.operatingstate),'Inactive'))       % Get current operating state
                app.sh05.operatingstate='Active' ;
            else
                app.sh05.operatingstate='Inactive' ;
            end%if
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.sh05.disconnect;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpencloseButton = uibutton(app.UIFigure, 'push');
            app.OpencloseButton.ButtonPushedFcn = createCallbackFcn(app, @OpencloseButtonPushed, true);
            app.OpencloseButton.Text = 'Open / close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thorlabs_Kinesis_Solenoid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end