classdef YOKOGAWA_CSUX1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AdvancedPanel                   matlab.ui.container.Panel
        DMnamesListBox                  matlab.ui.control.ListBox
        DMnamesListBoxLabel             matlab.ui.control.Label
        Filternames2ListBox             matlab.ui.control.ListBox
        Filternames2ListBoxLabel        matlab.ui.control.Label
        Filternames1ListBox             matlab.ui.control.ListBox
        Filternames1ListBoxLabel        matlab.ui.control.Label
        SavedichroicnamesButton         matlab.ui.control.Button
        SavefilternamesButton           matlab.ui.control.Button
        DichroicmirrorPanel             matlab.ui.container.Panel
        DMPositionSpinner               matlab.ui.control.Spinner
        DMPositionSpinnerLabel          matlab.ui.control.Label
        Filterwheel2Panel               matlab.ui.container.Panel
        FWPosition2Spinner              matlab.ui.control.Spinner
        FWPosition2SpinnerLabel         matlab.ui.control.Label
        FWSpeed2Spinner                 matlab.ui.control.Spinner
        FWSpeed2SpinnerLabel            matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        ShutterONOFFCheckBox            matlab.ui.control.CheckBox
        StatusPanel                     matlab.ui.container.Panel
        CheckrotationspeedButton        matlab.ui.control.Button
        CurrentrotationspeedEditField   matlab.ui.control.NumericEditField
        CurrentrotationspeedEditFieldLabel  matlab.ui.control.Label
        CurrentfilterspeedEditField     matlab.ui.control.NumericEditField
        CurrentfilterspeedEditFieldLabel  matlab.ui.control.Label
        CurrentfilterspeedEditField_2   matlab.ui.control.NumericEditField
        CurrentfilterspeedLabel         matlab.ui.control.Label
        CurrentFilternameEditField      matlab.ui.control.EditField
        CurrentFilternameEditFieldLabel  matlab.ui.control.Label
        CurrentFilternameEditField_2    matlab.ui.control.EditField
        CurrentFilternameEditField_2Label  matlab.ui.control.Label
        CurrentDMnameEditField          matlab.ui.control.EditField
        CurrentDMnameEditFieldLabel     matlab.ui.control.Label
        CurrentDMpositionEditField      matlab.ui.control.NumericEditField
        CurrentDMpositionEditFieldLabel  matlab.ui.control.Label
        CurrentfilterpositionEditField_2  matlab.ui.control.NumericEditField
        CurrentfilterpositionEditField_2Label  matlab.ui.control.Label
        CurrentfilterpositionEditField  matlab.ui.control.NumericEditField
        CurrentfilterpositionEditFieldLabel  matlab.ui.control.Label
        ShutterLamp                     matlab.ui.control.Lamp
        ShutterLampLabel                matlab.ui.control.Label
        NumberofOpenCloseEditField      matlab.ui.control.NumericEditField
        NumberofOpenCloseEditFieldLabel  matlab.ui.control.Label
        Filterwheel1Panel               matlab.ui.container.Panel
        FWPosition1Spinner              matlab.ui.control.Spinner
        FWPosition1SpinnerLabel         matlab.ui.control.Label
        FWSpeed1Spinner                 matlab.ui.control.Spinner
        FWSpeed1SpinnerLabel            matlab.ui.control.Label
        DiskPanel                       matlab.ui.container.Panel
        AdjustrotationtoexpCheckBox     matlab.ui.control.CheckBox
        RotationONOFFCheckBox           matlab.ui.control.CheckBox
        ExposuretimemsEditField         matlab.ui.control.NumericEditField
        ExposuretimemsEditFieldLabel    matlab.ui.control.Label
        DiskspeedrpmEditField           matlab.ui.control.NumericEditField
        DiskspeedrpmEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        COMPORT = "COM13" % COM PORT number
        BAUDRATE = 115200 % COM PORT BaudRate
        Serialport 
    end
    
    methods (Access = private)
        
        function ON = Check_Shutter_Status(app)
            resp = writeread(app.Serialport,"SH, ?");
            if contains(resp,"OPEN")
                ON = 1;
            else
                ON = 0;
            end%if
        end
        
        function value = Check_Shutter_Count(app)
            flush(app.Serialport);
            resp = writeread(app.Serialport,"SH_CNT, ?");
             k = strfind(resp,":A");
             charArray = char(resp);
             value = str2double(charArray(1:k-1));
        end

        function value = Check_Rotation_Speed(app)
             flush(app.Serialport);
             resp = writeread(app.Serialport,"MS, ?");
             k = strfind(resp,":A");
             charArray = char(resp);
             value = str2double(charArray(1:k-1));
        end

        function value = Check_FW_Position(app,FW)
             flush(app.Serialport);
             resp = writeread(app.Serialport,strcat("FW_POS, ",string(FW),", ?"));
             k = strfind(resp,":A");
             charArray = char(resp);
             value = str2double(charArray(1:k-1));
        end
        
        function value = Check_DM_Position(app)
             flush(app.Serialport);
             resp = writeread(app.Serialport,"DM_POS, ?");
             k = strfind(resp,":A");
             charArray = char(resp);
             value = str2double(charArray(1:k-1));    
        end
        
        function str = Check_DM_Name(app,DM)
            flush(app.Serialport);
            resp = writeread(app.Serialport,strcat("DM_INFO, ",string(DM),', ?'));
            k = strfind(resp,":A");
            charArray = char(resp);
            str = string(charArray(1:k-1));
        end
        function str = Check_Filter_Name(app,FW,Filter)
            flush(app.Serialport);
            resp = writeread(app.Serialport,strcat("FW_INFO, ",string(FW),', ',string(Filter),', ?'));
            k = strfind(resp,":A");
            charArray = char(resp);
            str = string(charArray(1:k-1));
        end

        
        function value = Check_FW_Speed(app,FW)
            flush(app.Serialport);
            resp = writeread(app.Serialport,strcat("FW_SPEED, ",string(FW),', ?'));
            k = strfind(resp,":A");
            charArray = char(resp);
            value = str2double(charArray(1:k-1));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Serialport  = serialport(app.COMPORT,app.BAUDRATE); 
            configureTerminator(app.Serialport,"CR")
            if  Check_Shutter_Status(app)
                app.ShutterLamp.Color=[0 1 0];
                app.ShutterONOFFCheckBox.Value=1;
            else
                app.ShutterLamp.Color=[0.5 0.5 0.5];
                app.ShutterONOFFCheckBox.Value=0;
            end%if
            app.NumberofOpenCloseEditField.Value = Check_Shutter_Count(app);
            app.CurrentfilterpositionEditField.Value = Check_FW_Position(app,1);
            app.FWPosition1Spinner.Value = app.CurrentfilterpositionEditField.Value;
            app.CurrentfilterpositionEditField_2.Value = Check_FW_Position(app,2);
            app.FWPosition2Spinner.Value = app.CurrentfilterpositionEditField_2.Value;
            app.CurrentDMpositionEditField.Value = Check_DM_Position(app);
            app.DMPositionSpinner.Value = app.CurrentDMpositionEditField.Value;
            app.CurrentDMnameEditField.Value = Check_DM_Name(app,app.DMPositionSpinner.Value);
            app.CurrentFilternameEditField.Value = Check_Filter_Name(app,1,app.FWPosition1Spinner.Value);
            app.CurrentFilternameEditField_2.Value = Check_Filter_Name(app,2,app.FWPosition2Spinner.Value);
            app.CurrentfilterspeedEditField.Value = Check_FW_Speed(app,1);
            app.CurrentfilterspeedEditField_2.Value = Check_FW_Speed(app,2);
            app.FWSpeed1Spinner.Value = Check_FW_Speed(app,1);
            app.FWSpeed2Spinner.Value = Check_FW_Speed(app,2);
            app.CurrentrotationspeedEditField.Value = Check_Rotation_Speed(app);
        end

        % Value changed function: ShutterONOFFCheckBox
        function ShutterONOFFCheckBoxValueChanged(app, event)
            if app.ShutterONOFFCheckBox.Value
                writeread(app.Serialport,"SHO");
            else
                writeread(app.Serialport,"SHC");
            end%if        

            if Check_Shutter_Status(app)
                app.ShutterLamp.Color=[0 1 0];
            else
                app.ShutterLamp.Color=[1 1 1];
            end%if
            app.NumberofOpenCloseEditField.Value = Check_Shutter_Count(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Serialport=[];
            delete(app);            
        end

        % Callback function
        function FWposition2EditFieldValueChanged(app, event)

        end

        % Callback function
        function FWspeed2EditFieldValueChanged(app, event)
            writeline(app.Serialport,strcat("FW_SPEED, 2, ",string(app.FWspeed2EditField.Value)));
        end

        % Value changed function: RotationONOFFCheckBox
        function RotationONOFFCheckBoxValueChanged(app, event)
            if app.RotationONOFFCheckBox.Value
                writeline(app.Serialport,"MS_RUN");
            else
                writeline(app.Serialport,"MS_STOP");
            end%if
            pause(3);
            app.CurrentrotationspeedEditField.Value = Check_Rotation_Speed(app);
        end

        % Value changed function: DiskspeedrpmEditField
        function DiskspeedrpmEditFieldValueChanged(app, event)
            writeline(app.Serialport,strcat("MS, ",string(app.DiskspeedrpmEditField.Value)));
            pause(3);
            app.CurrentrotationspeedEditField.Value = Check_Rotation_Speed(app);
        end

        % Value changed function: AdjustrotationtoexpCheckBox
        function AdjustrotationtoexpCheckBoxValueChanged(app, event)
            if app.AdjustrotationtoexpCheckBox.Value
                writeline(app.Serialport,strcat("MS_ADJUST, ",string(app.ExposuretimemsEditField.Value)));
            end%if
            pause(3);
            app.CurrentrotationspeedEditField.Value = Check_Rotation_Speed(app);
        end

        % Value changed function: FWSpeed1Spinner
        function FWSpeed1SpinnerValueChanged(app, event)
            writeline(app.Serialport,strcat("FW_SPEED, 1, ",string(app.FWSpeed1Spinner.Value))); 
            pause(0.1);
            app.CurrentfilterspeedEditField.Value = Check_FW_Speed(app,1);
        end

        % Value changed function: FWSpeed2Spinner
        function FWSpeed2SpinnerValueChanged(app, event)
                writeline(app.Serialport,strcat("FW_SPEED, 2, ",string(app.FWSpeed2Spinner.Value))); 
                pause(0.1);
                app.CurrentfilterspeedEditField_2.Value = Check_FW_Speed(app,2);
        end

        % Value changed function: FWPosition1Spinner
        function FWPosition1SpinnerValueChanged(app, event)
            writeline(app.Serialport,strcat("FW_POS, 1, ",string(app.FWPosition1Spinner.Value)));
            pause(0.1);
            app.CurrentfilterpositionEditField.Value = Check_FW_Position(app,1);
            pause(0.1);
            app.CurrentFilternameEditField.Value = Check_Filter_Name(app,1,app.FWPosition1Spinner.Value);
        end

        % Value changed function: FWPosition2Spinner
        function FWPosition2SpinnerValueChanged(app, event)
            writeline(app.Serialport,strcat("FW_POS, 2, ",string(app.FWPosition2Spinner.Value))); 
            pause(0.1);
            app.CurrentfilterpositionEditField_2.Value = Check_FW_Position(app,2);
            pause(0.1);
            app.CurrentFilternameEditField_2.Value = Check_Filter_Name(app,2,app.FWPosition2Spinner.Value);
        end

        % Value changed function: DMPositionSpinner
        function DMPositionSpinnerValueChanged(app, event)
            writeline(app.Serialport,strcat("DM_POS, ",string(app.DMPositionSpinner.Value)));
            pause(1);
            app.CurrentDMpositionEditField.Value = Check_DM_Position(app);
            pause(1);
            app.CurrentDMnameEditField.Value = Check_DM_Name(app,app.DMPositionSpinner.Value);
        end

        % Button pushed function: SavefilternamesButton
        function SavefilternamesButtonPushed(app, event)
            for i=1:6
                writeline(app.Serialport,strcat("FW_INFO, 1, ", num2str(i),', "', string(app.Filternames1ListBox.Items(i)),'"'));
                strcat("FW_INFO, 1, ", num2str(i), string(app.Filternames1ListBox.Items(i)))
                pause(0.1);
            end%if
            for i=1:6
                writeline(app.Serialport,strcat("FW_INFO, 2, ", num2str(i),', "',string(app.Filternames2ListBox.Items(i)),'"'));
                pause(0.1);
            end%if
        end

        % Button pushed function: SavedichroicnamesButton
        function SavedichroicnamesButtonPushed(app, event)
            for i=1:2
               writeline(app.Serialport,strcat("DM_INFO, ", num2str(i), ', "',string(app.DMnamesListBox.Items(i)),'"'));
               pause(0.1);
            end%if
        end

        % Button pushed function: CheckrotationspeedButton
        function CheckrotationspeedButtonPushed(app, event)
            app.CurrentrotationspeedEditField.Value = Check_Rotation_Speed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DiskPanel = uipanel(app.UIFigure);
            app.DiskPanel.Title = 'Disk';
            app.DiskspeedrpmEditFieldLabel = uilabel(app.DiskPanel);
            app.DiskspeedrpmEditFieldLabel.Text = 'Disk speed (rpm)';
            app.DiskspeedrpmEditField = uieditfield(app.DiskPanel, 'numeric');
            app.DiskspeedrpmEditField.Limits = [0 5000];
            app.DiskspeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @DiskspeedrpmEditFieldValueChanged, true);
            app.DiskspeedrpmEditField.Value = 5000;
            app.ExposuretimemsEditFieldLabel = uilabel(app.DiskPanel);
            app.ExposuretimemsEditFieldLabel.Text = 'Exposure time (ms)';
            app.ExposuretimemsEditField = uieditfield(app.DiskPanel, 'numeric');
            app.ExposuretimemsEditField.Value = 50;
            app.RotationONOFFCheckBox = uicheckbox(app.DiskPanel);
            app.RotationONOFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotationONOFFCheckBoxValueChanged, true);
            app.RotationONOFFCheckBox.Text = 'Rotation ON/OFF';
            app.RotationONOFFCheckBox.Value = true;
            app.AdjustrotationtoexpCheckBox = uicheckbox(app.DiskPanel);
            app.AdjustrotationtoexpCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdjustrotationtoexpCheckBoxValueChanged, true);
            app.AdjustrotationtoexpCheckBox.Text = 'Adjust rotation to exp.';
            app.Filterwheel1Panel = uipanel(app.UIFigure);
            app.Filterwheel1Panel.Title = 'Filter wheel 1';
            app.FWSpeed1SpinnerLabel = uilabel(app.Filterwheel1Panel);
            app.FWSpeed1SpinnerLabel.Text = 'FW Speed 1';
            app.FWSpeed1Spinner = uispinner(app.Filterwheel1Panel);
            app.FWSpeed1Spinner.Limits = [1 3];
            app.FWSpeed1Spinner.ValueChangedFcn = createCallbackFcn(app, @FWSpeed1SpinnerValueChanged, true);
            app.FWSpeed1Spinner.Value = 1;
            app.FWPosition1SpinnerLabel = uilabel(app.Filterwheel1Panel);
            app.FWPosition1SpinnerLabel.Text = 'FW  Position 1';
            app.FWPosition1Spinner = uispinner(app.Filterwheel1Panel);
            app.FWPosition1Spinner.Limits = [1 6];
            app.FWPosition1Spinner.ValueChangedFcn = createCallbackFcn(app, @FWPosition1SpinnerValueChanged, true);
            app.FWPosition1Spinner.Value = 1;
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.NumberofOpenCloseEditFieldLabel = uilabel(app.StatusPanel);
            app.NumberofOpenCloseEditFieldLabel.Text = 'Number of Open/Close';
            app.NumberofOpenCloseEditField = uieditfield(app.StatusPanel, 'numeric');
            app.NumberofOpenCloseEditField.Editable = 'off';
            app.ShutterLampLabel = uilabel(app.StatusPanel);
            app.ShutterLampLabel.Text = 'Shutter';
            app.ShutterLamp = uilamp(app.StatusPanel);
            app.CurrentfilterpositionEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentfilterpositionEditFieldLabel.Text = 'Current filter position';
            app.CurrentfilterpositionEditField = uieditfield(app.StatusPanel, 'numeric');
            app.CurrentfilterpositionEditField.Limits = [1 6];
            app.CurrentfilterpositionEditField.Editable = 'off';
            app.CurrentfilterpositionEditField.Value = 1;
            app.CurrentfilterpositionEditField_2Label = uilabel(app.StatusPanel);
            app.CurrentfilterpositionEditField_2Label.Text = 'Current filter position';
            app.CurrentfilterpositionEditField_2 = uieditfield(app.StatusPanel, 'numeric');
            app.CurrentfilterpositionEditField_2.Limits = [1 6];
            app.CurrentfilterpositionEditField_2.Editable = 'off';
            app.CurrentfilterpositionEditField_2.Value = 1;
            app.CurrentDMpositionEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentDMpositionEditFieldLabel.Text = 'Current DM position';
            app.CurrentDMpositionEditField = uieditfield(app.StatusPanel, 'numeric');
            app.CurrentDMpositionEditField.Limits = [1 6];
            app.CurrentDMpositionEditField.Editable = 'off';
            app.CurrentDMpositionEditField.Value = 1;
            app.CurrentDMnameEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentDMnameEditFieldLabel.Text = 'Current DM name';
            app.CurrentDMnameEditField = uieditfield(app.StatusPanel, 'text');
            app.CurrentFilternameEditField_2Label = uilabel(app.StatusPanel);
            app.CurrentFilternameEditField_2Label.Text = 'Current Filter name';
            app.CurrentFilternameEditField_2 = uieditfield(app.StatusPanel, 'text');
            app.CurrentFilternameEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentFilternameEditFieldLabel.Text = 'Current Filter name';
            app.CurrentFilternameEditField = uieditfield(app.StatusPanel, 'text');
            app.CurrentfilterspeedLabel = uilabel(app.StatusPanel);
            app.CurrentfilterspeedLabel.Text = 'Current filter speed';
            app.CurrentfilterspeedEditField_2 = uieditfield(app.StatusPanel, 'numeric');
            app.CurrentfilterspeedEditField_2.Limits = [1 6];
            app.CurrentfilterspeedEditField_2.Editable = 'off';
            app.CurrentfilterspeedEditField_2.Value = 1;
            app.CurrentfilterspeedEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentfilterspeedEditFieldLabel.Text = 'Current filter speed';
            app.CurrentfilterspeedEditField = uieditfield(app.StatusPanel, 'numeric');
            app.CurrentfilterspeedEditField.Limits = [1 6];
            app.CurrentfilterspeedEditField.Editable = 'off';
            app.CurrentfilterspeedEditField.Value = 1;
            app.CurrentrotationspeedEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentrotationspeedEditFieldLabel.Text = 'Current rotation speed';
            app.CurrentrotationspeedEditField = uieditfield(app.StatusPanel, 'numeric');
            app.CurrentrotationspeedEditField.Limits = [0 50000];
            app.CurrentrotationspeedEditField.Editable = 'off';
            app.CurrentrotationspeedEditField.Value = 1;
            app.CheckrotationspeedButton = uibutton(app.StatusPanel, 'push');
            app.CheckrotationspeedButton.ButtonPushedFcn = createCallbackFcn(app, @CheckrotationspeedButtonPushed, true);
            app.CheckrotationspeedButton.Text = 'Check rotation speed';
            app.GeneralPanel = uipanel(app.UIFigure);
            app.GeneralPanel.Title = 'General';
            app.ShutterONOFFCheckBox = uicheckbox(app.GeneralPanel);
            app.ShutterONOFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShutterONOFFCheckBoxValueChanged, true);
            app.ShutterONOFFCheckBox.Text = 'Shutter ON/OFF';
            app.Filterwheel2Panel = uipanel(app.UIFigure);
            app.Filterwheel2Panel.Title = 'Filter wheel 2';
            app.FWSpeed2SpinnerLabel = uilabel(app.Filterwheel2Panel);
            app.FWSpeed2SpinnerLabel.Text = 'FW Speed 2';
            app.FWSpeed2Spinner = uispinner(app.Filterwheel2Panel);
            app.FWSpeed2Spinner.Limits = [1 3];
            app.FWSpeed2Spinner.ValueChangedFcn = createCallbackFcn(app, @FWSpeed2SpinnerValueChanged, true);
            app.FWSpeed2Spinner.Value = 1;
            app.FWPosition2SpinnerLabel = uilabel(app.Filterwheel2Panel);
            app.FWPosition2SpinnerLabel.Text = 'FW  Position 2';
            app.FWPosition2Spinner = uispinner(app.Filterwheel2Panel);
            app.FWPosition2Spinner.Limits = [1 6];
            app.FWPosition2Spinner.ValueChangedFcn = createCallbackFcn(app, @FWPosition2SpinnerValueChanged, true);
            app.FWPosition2Spinner.Value = 1;
            app.DichroicmirrorPanel = uipanel(app.UIFigure);
            app.DichroicmirrorPanel.Title = 'Dichroic mirror';
            app.DMPositionSpinnerLabel = uilabel(app.DichroicmirrorPanel);
            app.DMPositionSpinnerLabel.Text = 'DM  Position';
            app.DMPositionSpinner = uispinner(app.DichroicmirrorPanel);
            app.DMPositionSpinner.Limits = [1 2];
            app.DMPositionSpinner.ValueChangedFcn = createCallbackFcn(app, @DMPositionSpinnerValueChanged, true);
            app.DMPositionSpinner.Value = 1;
            app.AdvancedPanel = uipanel(app.UIFigure);
            app.AdvancedPanel.Title = 'Advanced';
            app.SavefilternamesButton = uibutton(app.AdvancedPanel, 'push');
            app.SavefilternamesButton.ButtonPushedFcn = createCallbackFcn(app, @SavefilternamesButtonPushed, true);
            app.SavefilternamesButton.Text = 'Save filter names';
            app.SavedichroicnamesButton = uibutton(app.AdvancedPanel, 'push');
            app.SavedichroicnamesButton.ButtonPushedFcn = createCallbackFcn(app, @SavedichroicnamesButtonPushed, true);
            app.SavedichroicnamesButton.Text = 'Save dichroic names';
            app.Filternames1ListBoxLabel = uilabel(app.AdvancedPanel);
            app.Filternames1ListBoxLabel.Text = 'Filter names 1';
            app.Filternames1ListBox = uilistbox(app.AdvancedPanel);
            app.Filternames1ListBox.Items = {'447BP60', '475BP35', '525BP50', '600BP37', '647BP57', '647LP88'};
            app.Filternames1ListBox.Value = '447BP60';
            app.Filternames2ListBoxLabel = uilabel(app.AdvancedPanel);
            app.Filternames2ListBoxLabel.Text = 'Filter names 2';
            app.Filternames2ListBox = uilistbox(app.AdvancedPanel);
            app.Filternames2ListBox.Items = {'447BP60', '475BP35', '525BP50', '600BP37', '647BP57', '647LP'};
            app.Filternames2ListBox.Value = '447BP60';
            app.DMnamesListBoxLabel = uilabel(app.AdvancedPanel);
            app.DMnamesListBoxLabel.Text = 'DM names';
            app.DMnamesListBox = uilistbox(app.AdvancedPanel);
            app.DMnamesListBox.Items = {'405/488/568/647', '445/514/593'};
            app.DMnamesListBox.Value = '405/488/568/647';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YOKOGAWA_CSUX1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end